classdef GENERATE_ATLAS_FILES < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        locate_ccf         matlab.ui.control.Button
        locate_kim_atlas   matlab.ui.control.Button
        locate_ccf_labels  matlab.ui.control.Button
        ccf_path           matlab.ui.control.EditField
        ccf_labels_path    matlab.ui.control.EditField
        kim_atlas_path     matlab.ui.control.EditField
        go                 matlab.ui.control.Button
        status_text        matlab.ui.control.TextArea
        locate_save_path   matlab.ui.control.Button
        save_path          matlab.ui.control.EditField
    end

    
    methods (Access = private)
        
        % the fast way to save a tif
        function save_atlas(app, data, filepath)
            fTIF = Fast_BigTiff_Write(filepath,0,Tiff.Compression.LZW);    
            for i = 1:size(data,3)
                fTIF.WriteIMG(data(:,:,i));
            end
            fTIF.close;
        end
        % use bfsave for CCF labels because it's unit32
        function save_atlas_uint32(app, data, filepath)
            bfsave(data,filepath,'Compression','LZW');
        end
        % update status text field
        function update_status(app, update_str)
            app.status_text.Value = update_str;
            pause(0.5)
    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on'; 
            pause(1)
        end

        % Callback function: ccf_path, locate_ccf
        function locate_CCF_button_pushed(app, event)
            [filename,filepath] = uigetfile('*.nrrd');
            app.ccf_path.Value = fullfile(filepath,filename);
        end

        % Callback function: ccf_labels_path, locate_ccf_labels
        function locate_CCF_annotation_button_pushed(app, event)
            [filename,filepath] = uigetfile('*.nrrd');
            app.ccf_labels_path.Value = fullfile(filepath,filename);
        end

        % Callback function: kim_atlas_path, locate_kim_atlas
        function locate_kim_atlas_button_pushed(app, event)
            filepath = uigetdir;
            app.kim_atlas_path.Value = filepath;
        end

        % Button pushed function: locate_save_path
        function locate_save_pathButtonPushed(app, event)
            filepath = uigetdir;
            app.save_path.Value = filepath;
        end

        % Button pushed function: go
        function ok_go_button_pushed(app, event)
            
            if ~exist(app.ccf_path.Value,'file') || ~exist(app.ccf_labels_path.Value,'file') || ~exist(app.kim_atlas_path.Value,'dir')
                update_status(app,'1 or more of the files above is missing, or cannot be found. Please try again.');
            
            else % all exist
                       
                % the directory this is in
                mri_dir = fullfile(app.save_path.Value, 'MRIAtlas');
                if ~exist(mri_dir,'dir')
                    mkdir(mri_dir)
                end
                if ~exist(fullfile(mri_dir,'CCF'),'dir')
                    mkdir (fullfile(mri_dir,'CCF'))
                end
                if ~exist(fullfile(mri_dir,'Chon'),'dir')
                    mkdir (fullfile(mri_dir,'Chon'))
                end
                
                
                % CCF atlas
                update_status(app,'Step 1 of 3: Loading and saving Allen Mouse Brain CCF atlas...');
                this_brain = bfopen(app.ccf_path.Value);
                this_brain = this_brain{1}(:,1);
                this_brain = permute(reshape(transpose(...
                    cell2mat(this_brain)),...
                    size(this_brain{1},2),size(this_brain{1},1),size(this_brain,1)),...
                    [2 3 1]);
                
                save_atlas(app,this_brain,fullfile(mri_dir,'CCF','average_template_10_coronal.tif'));
                
                % CCF labels
                update_status(app,'Step 2 of 3: Loading and saving Allen Mouse Brain CCF atlas annotation labels...');
                this_brain = bfopen(app.ccf_labels_path.Value);
                this_brain = this_brain{1}(:,1);
                this_brain = permute(reshape(transpose(...
                    cell2mat(this_brain)),...
                    size(this_brain{1},2),size(this_brain{1},1),size(this_brain,1)),...
                    [2 3 1]);
                save_atlas_uint32(app,this_brain,fullfile(mri_dir,'CCF','annotation_10_coronal.tif'));
                
                % Chon et al CCF
                update_status(app,'Step 3 of 3: Loading and saving Kim Lab atlas and annotation labels...');
                brain_dir = fullfile(app.kim_atlas_path.Value,'Suppl_File2_CCFbackground');
                brain_files = dir(brain_dir);
                brain_files = {brain_files.name}';
                brain_files = brain_files(endsWith(brain_files,'.tif'));
                brain_size = imfinfo(fullfile(brain_dir,brain_files{1}));
                this_brain = zeros(brain_size.Height,brain_size.Width,numel(brain_files));
                for i = 1:numel(brain_files)
                    this_brain(:,:,i) = tiffreadVolume(fullfile(brain_dir,brain_files{i}));
                end
                save_atlas(app,uint16(this_brain),fullfile(mri_dir,'Chon','Chon_CCF_coronal.tif'));

                % Chon et al labels
                brain_dir = fullfile(app.kim_atlas_path.Value,'Suppl_File1_Labels');
                brain_files = dir(brain_dir);
                brain_files = {brain_files.name}';
                brain_files = brain_files(endsWith(brain_files,'.tif'));
                brain_file_nums = cellfun(@(x) strsplit(x,'_AP'),brain_files,'UniformOutput',false);
                brain_file_nums = cellfun(@(x) str2double(x{1}),brain_file_nums);
                [~,brain_file_idx] = sort(brain_file_nums);
                brain_files = brain_files(brain_file_idx);
                brain_size = imfinfo(fullfile(brain_dir,brain_files{1}));
                this_brain = zeros(brain_size.Height,brain_size.Width,numel(brain_files));
                for i = 1:numel(brain_files)
                    this_brain(:,:,i) = tiffreadVolume(fullfile(brain_dir,brain_files{i}));
                end
                save_atlas(app,uint16(this_brain),fullfile(mri_dir,'Chon','Chon_labels_coronal.tif'));
                
                % copy keys to subfolders
                copyfile(which(fullfile('ccf_key.mat')),fullfile(mri_dir,'CCF','ccf_key.mat'));
                copyfile(which(fullfile('landmarks.points')),fullfile(mri_dir,'CCF','landmarks.points'));
                copyfile(which(fullfile('chon_key.mat')),fullfile(mri_dir,'Chon','chon_key.mat'));               

                
                % display finished
                if exist(fullfile(mri_dir,'CCF','average_template_10_coronal.tif'),'file') && ...
                        exist(fullfile(mri_dir,'CCF','annotation_10_coronal.tif'),'file') && ...
                        exist(fullfile(mri_dir,'CCF','ccf_key.mat'),'file') && ...
                        exist(fullfile(mri_dir,'CCF','landmarks.points'),'file') && ...
                        exist(fullfile(mri_dir,'Chon','Chon_CCF_coronal.tif'),'file') && ...
                        exist(fullfile(mri_dir,'Chon','Chon_labels_coronal.tif'),'file') && ...
                        exist(fullfile(mri_dir,'Chon','chon_key.mat'),'file')
                    update_status(app,'Finished. You can close this.');
                else
                    update_status(app,'One or more of the output files was not saved. You may need to try again.');
                end       
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.locate_ccf = uibutton(app.UIFigure, 'push');
            app.locate_ccf.ButtonPushedFcn = createCallbackFcn(app, @locate_CCF_button_pushed, true);
            app.locate_ccf.Text = 'Locate average_template_10.nrrd (CCF)';
            app.locate_kim_atlas = uibutton(app.UIFigure, 'push');
            app.locate_kim_atlas.ButtonPushedFcn = createCallbackFcn(app, @locate_kim_atlas_button_pushed, true);
            app.locate_kim_atlas.Text = 'Locate folder containing unzipped Kim Lab atlas files';
            app.locate_ccf_labels = uibutton(app.UIFigure, 'push');
            app.locate_ccf_labels.ButtonPushedFcn = createCallbackFcn(app, @locate_CCF_annotation_button_pushed, true);
            app.locate_ccf_labels.Text = 'Locate annotation_10.nrrd (CCF)';
            app.ccf_path = uieditfield(app.UIFigure, 'text');
            app.ccf_path.ValueChangingFcn = createCallbackFcn(app, @locate_CCF_button_pushed, true);
            app.ccf_path.Editable = 'off';
            app.ccf_path.Value = 'path/to/average_template_10.nnrd';
            app.ccf_labels_path = uieditfield(app.UIFigure, 'text');
            app.ccf_labels_path.ValueChangingFcn = createCallbackFcn(app, @locate_CCF_annotation_button_pushed, true);
            app.ccf_labels_path.Editable = 'off';
            app.ccf_labels_path.Value = 'path/to/annotation_10.nrrd';
            app.kim_atlas_path = uieditfield(app.UIFigure, 'text');
            app.kim_atlas_path.ValueChangingFcn = createCallbackFcn(app, @locate_kim_atlas_button_pushed, true);
            app.kim_atlas_path.Editable = 'off';
            app.kim_atlas_path.Value = 'path/to/atlas_web_release_data';
            app.go = uibutton(app.UIFigure, 'push');
            app.go.ButtonPushedFcn = createCallbackFcn(app, @ok_go_button_pushed, true);
            app.go.Text = 'Ok Go!';
            app.status_text = uitextarea(app.UIFigure);
            app.status_text.Editable = 'off';
            app.locate_save_path = uibutton(app.UIFigure, 'push');
            app.locate_save_path.ButtonPushedFcn = createCallbackFcn(app, @locate_save_pathButtonPushed, true);
            app.locate_save_path.Text = 'Choose location to save atlas files';
            app.save_path = uieditfield(app.UIFigure, 'text');
            app.save_path.Editable = 'off';
            app.save_path.Value = 'path/to/atlas_location';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GENERATE_ATLAS_FILES

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end