classdef LOCALIZE_FIBERS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FIBERTOPSbutton           matlab.ui.control.Button
        FIBERAREAbutton           matlab.ui.control.Button
        FIBERTIPSbutton           matlab.ui.control.Button
        MULTIFIBERSbutton         matlab.ui.control.Button
        REFINETOPSbutton          matlab.ui.control.Button
        ATLASINFOSAVEbutton       matlab.ui.control.Button
        VERIFYLABELSbutton        matlab.ui.control.Button
        locate_ct                 matlab.ui.control.Button
        path_ct                   matlab.ui.control.EditField
        locate_inprog             matlab.ui.control.Button
        path_inprog               matlab.ui.control.EditField
        loadbutton                matlab.ui.control.Button
        savebutton                matlab.ui.control.Button
        closebutton               matlab.ui.control.Button
        currentStatus             matlab.ui.control.TextArea
        MultifiberSepButtonGroup  matlab.ui.container.ButtonGroup
        watershedButton           matlab.ui.control.RadioButton
        GMMButton                 matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        output = struct; % output struct
    end
    
    methods (Access = public)
        
        function update_localization(app,output,update_str)
            app.output = output; % update output    
            update_status(app,update_str)
            enable_steps(app)
        end
    end
    
    methods (Access = private)
        
        function update_status(app,update_str)
            app.currentStatus.Value = update_str;
            pause(0.5)
            
        end
        
        function disable_steps(app)
            app.FIBERTOPSbutton.Enable = 'off';
            app.REFINETOPSbutton.Enable = 'off';
            app.FIBERAREAbutton.Enable = 'off';
            app.FIBERTIPSbutton.Enable = 'off';
            app.MULTIFIBERSbutton.Enable = 'off';
            app.MultifiberSepButtonGroup.Enable = 'off';
            app.ATLASINFOSAVEbutton.Enable = 'off';
            app.VERIFYLABELSbutton.Enable = 'off';           
        end
        
        function enable_steps(app)
            app.FIBERTOPSbutton.Enable = 'on';
            app.REFINETOPSbutton.Enable = 'on';
            app.FIBERAREAbutton.Enable = 'on';
            app.FIBERTIPSbutton.Enable = 'on';
            app.MULTIFIBERSbutton.Enable = 'on';
            app.MultifiberSepButtonGroup.Enable = 'on';
            app.ATLASINFOSAVEbutton.Enable = 'on';
            app.VERIFYLABELSbutton.Enable = 'on';           
        end
        
        function save_progress(app,is_final)
            % don't need to save out the CT - we'll just reload that every time
            prog_output = rmfield(app.output,'ct'); 
            save(fullfile(app.output.info.savepath,[app.output.info.ctname '_loc_inprog.mat']),'-struct','prog_output')            
            if is_final == 1 % if this is the final version, let's call it _localized_fibers.mat
                movefile(fullfile(app.output.info.savepath,[app.output.info.ctname '_loc_inprog.mat']),...
                    fullfile(app.output.info.savepath,[app.output.info.ctname '_localized_fibers.mat']))
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FIBERTOPSbutton
        function FIBERTOPSbuttonButtonPushed(app, event)
            update_status(app,'opening fiber top identification app')
            fiber_top_ID(app,app.output);
        end

        % Button pushed function: REFINETOPSbutton
        function REFINETOPSbuttonButtonPushed(app, event)
            update_status(app,'opening fiber top refinement app')
            fiber_top_refine_and_label(app,app.output);
        end

        % Button pushed function: FIBERAREAbutton
        function FIBERAREAbuttonButtonPushed(app, event)
            update_status(app,'opening fiber area identification app')
            fiber_area_ID(app,app.output);
        end

        % Button pushed function: FIBERTIPSbutton
        function FIBERTIPSbuttonButtonPushed(app, event)
            update_status(app,'mapping fiber tips - this may take a little while')
            app.output = map_fiber_tips(app.output,'watershed_sep',app.watershedButton.Value);
            update_status(app,'finished mapping fiber tips')

        end

        % Button pushed function: MULTIFIBERSbutton
        function MULTIFIBERSbuttonButtonPushed(app, event)
            update_status(app,'opening app for checking multlifibers')
            check_multifibers(app,app.output);
        end

        % Button pushed function: ATLASINFOSAVEbutton
        function ATLASINFOSAVEbuttonButtonPushed(app, event)
            update_status(app,'opening app for saving atlas info')
            add_atlas_info_and_save(app,app.output)
        end

        % Button pushed function: VERIFYLABELSbutton
        function VERIFYLABELSbuttonButtonPushed(app, event)
            update_status(app,'opening app to verify atlas labels; you may close this app now')
            verify_atlas_labels
        end

        % Button pushed function: locate_inprog
        function locate_inprogButtonPushed(app, event)
            [filename,filepath] = uigetfile('*.mat');
            app.path_inprog.Value = fullfile(filepath,filename);
        end

        % Button pushed function: locate_ct
        function locate_ctButtonPushed(app, event)
            [filename,filepath] = uigetfile('*.tif');
            app.path_ct.Value = fullfile(filepath,filename);
        end

        % Button pushed function: loadbutton
        function loadbuttonButtonPushed(app, event)
            update_status(app,'loading things... ')
            % load in progress if applicable
            if exist(app.path_inprog.Value,'file')
                app.output = load(app.path_inprog.Value);
            end
            % load in ct and note path
            app.output.ct = load_tiffs_fast(app.path_ct.Value);
            app.output.info.ct_size = size(app.output.ct);
            [app.output.info.savepath,app.output.info.ctname] = fileparts(app.path_ct.Value);      
            % enable other buttons
            enable_steps(app)
            app.savebutton.Enable = 'on';
            % disable some things
            app.locate_inprog.Enable = 'off';
            app.path_inprog.Enable = 'off';
            app.locate_ct.Enable = 'off';
            app.path_ct.Enable = 'off';
            app.loadbutton.Enable = 'off';
            % update status
            update_status(app,'loaded.')

        end

        % Button pushed function: savebutton
        function savebuttonButtonPushed(app, event)
            update_status(app,'saving ...')
            if exist(fullfile(app.output.info.savepath,[app.output.info.ctname '_localized_fibers.mat']),'file')
                is_final = 1;
            else
                is_final = 0;
            end
            save_progress(app,is_final)
            update_status(app,'... saved')
        end

        % Button pushed function: closebutton
        function closebuttonButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FIBERTOPSbutton = uibutton(app.UIFigure, 'push');
            app.FIBERTOPSbutton.ButtonPushedFcn = createCallbackFcn(app, @FIBERTOPSbuttonButtonPushed, true);
            app.FIBERTOPSbutton.Enable = 'off';
            app.FIBERTOPSbutton.Text = '1. IDENTIFY FIBER TOPS (opens separate app)';
            app.FIBERAREAbutton = uibutton(app.UIFigure, 'push');
            app.FIBERAREAbutton.ButtonPushedFcn = createCallbackFcn(app, @FIBERAREAbuttonButtonPushed, true);
            app.FIBERAREAbutton.Enable = 'off';
            app.FIBERAREAbutton.Text = '3. IDENTIFY FIBER AREA (opens separate app)';
            app.FIBERTIPSbutton = uibutton(app.UIFigure, 'push');
            app.FIBERTIPSbutton.ButtonPushedFcn = createCallbackFcn(app, @FIBERTIPSbuttonButtonPushed, true);
            app.FIBERTIPSbutton.Enable = 'off';
            app.FIBERTIPSbutton.Text = '4. MAP FIBER TIPS';
            app.MULTIFIBERSbutton = uibutton(app.UIFigure, 'push');
            app.MULTIFIBERSbutton.ButtonPushedFcn = createCallbackFcn(app, @MULTIFIBERSbuttonButtonPushed, true);
            app.MULTIFIBERSbutton.Enable = 'off';
            app.MULTIFIBERSbutton.Text = '5. CHECK MULTIFIBERS (opens separate app)';
            app.REFINETOPSbutton = uibutton(app.UIFigure, 'push');
            app.REFINETOPSbutton.ButtonPushedFcn = createCallbackFcn(app, @REFINETOPSbuttonButtonPushed, true);
            app.REFINETOPSbutton.Enable = 'off';
            app.REFINETOPSbutton.Text = '2. REFINE FIBER TOPS & ASSIGN LABELS (opens separate app)';
            app.ATLASINFOSAVEbutton = uibutton(app.UIFigure, 'push');
            app.ATLASINFOSAVEbutton.ButtonPushedFcn = createCallbackFcn(app, @ATLASINFOSAVEbuttonButtonPushed, true);
            app.ATLASINFOSAVEbutton.Enable = 'off';
            app.ATLASINFOSAVEbutton.Text = '6. ADD ATLAS INFO & SAVE FINAL OUTPUT (opens separate app)';
            app.VERIFYLABELSbutton = uibutton(app.UIFigure, 'push');
            app.VERIFYLABELSbutton.ButtonPushedFcn = createCallbackFcn(app, @VERIFYLABELSbuttonButtonPushed, true);
            app.VERIFYLABELSbutton.Enable = 'off';
            app.VERIFYLABELSbutton.Text = '7. VERIFY LABELS (opens separate app)';
            app.locate_ct = uibutton(app.UIFigure, 'push');
            app.locate_ct.ButtonPushedFcn = createCallbackFcn(app, @locate_ctButtonPushed, true);
            app.locate_ct.Text = 'Locate CT';
            app.path_ct = uieditfield(app.UIFigure, 'text');
            app.path_ct.Value = 'path to registered CT .tif file';
            app.locate_inprog = uibutton(app.UIFigure, 'push');
            app.locate_inprog.ButtonPushedFcn = createCallbackFcn(app, @locate_inprogButtonPushed, true);
            app.locate_inprog.Text = 'Locate inprog';
            app.path_inprog = uieditfield(app.UIFigure, 'text');
            app.path_inprog.Value = 'path to inprog.mat file (optional)';
            app.loadbutton = uibutton(app.UIFigure, 'push');
            app.loadbutton.ButtonPushedFcn = createCallbackFcn(app, @loadbuttonButtonPushed, true);
            app.loadbutton.Text = 'Load';
            app.savebutton = uibutton(app.UIFigure, 'push');
            app.savebutton.ButtonPushedFcn = createCallbackFcn(app, @savebuttonButtonPushed, true);
            app.savebutton.Enable = 'off';
            app.savebutton.Text = 'Save progress';
            app.closebutton = uibutton(app.UIFigure, 'push');
            app.closebutton.ButtonPushedFcn = createCallbackFcn(app, @closebuttonButtonPushed, true);
            app.closebutton.Text = 'Close';
            app.currentStatus = uitextarea(app.UIFigure);
            app.MultifiberSepButtonGroup = uibuttongroup(app.UIFigure);
            app.MultifiberSepButtonGroup.Enable = 'off';
            app.MultifiberSepButtonGroup.Title = 'Multifiber separation algorithm';
            app.watershedButton = uiradiobutton(app.MultifiberSepButtonGroup);
            app.watershedButton.Text = 'watershed';
            app.watershedButton.Value = true;
            app.GMMButton = uiradiobutton(app.MultifiberSepButtonGroup);
            app.GMMButton.Text = 'GMM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LOCALIZE_FIBERS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end