classdef REGISTER_CT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        orientCTButton              matlab.ui.control.Button
        manuallyrefineCTregistrationButton  matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        initalCTregistrationButton  matlab.ui.control.Button
        locate_landmarks            matlab.ui.control.Button
        path_landmarks              matlab.ui.control.EditField
        locate_ct                   matlab.ui.control.Button
        path_ct                     matlab.ui.control.EditField
        locate_atlas                matlab.ui.control.Button
        path_atlas                  matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on'; 
            pause(1)
        end

        % Button pushed function: orientCTButton
        function orientCT_button(app, event)
            run('orient_CT')
        end

        % Button pushed function: manuallyrefineCTregistrationButton
        function manual_refine_button(app, event)
            run('manual_refine_registration')
        end

        % Button pushed function: locate_ct
        function locate_ctButtonPushed(app, event)
            [filename,filepath] = uigetfile('*.tif');
            app.path_ct.Value = fullfile(filepath,filename);
        end

        % Button pushed function: locate_landmarks
        function locate_landmarksButtonPushed(app, event)
            [filename,filepath] = uigetfile('*.points');
            app.path_landmarks.Value = fullfile(filepath,filename);
        end

        % Button pushed function: locate_atlas
        function locate_atlasButtonPushed(app, event)
            filepath = uigetdir;
            app.path_atlas.Value = filepath;
        end

        % Button pushed function: initalCTregistrationButton
        function register_button(app, event)
            landmark_registration(app.path_ct.Value,app.path_landmarks.Value,app.path_atlas.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.orientCTButton = uibutton(app.UIFigure, 'push');
            app.orientCTButton.ButtonPushedFcn = createCallbackFcn(app, @orientCT_button, true);
            app.orientCTButton.Text = 'orient CT';
            app.manuallyrefineCTregistrationButton = uibutton(app.UIFigure, 'push');
            app.manuallyrefineCTregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @manual_refine_button, true);
            app.manuallyrefineCTregistrationButton.Text = 'manually refine CT registration';
            app.Panel = uipanel(app.UIFigure);
            app.initalCTregistrationButton = uibutton(app.Panel, 'push');
            app.initalCTregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @register_button, true);
            app.initalCTregistrationButton.Text = 'inital CT registration';
            app.locate_landmarks = uibutton(app.Panel, 'push');
            app.locate_landmarks.ButtonPushedFcn = createCallbackFcn(app, @locate_landmarksButtonPushed, true);
            app.locate_landmarks.Text = 'Locate landmarks';
            app.path_landmarks = uieditfield(app.Panel, 'text');
            app.path_landmarks.Value = '/path/to/CT_landmarks.points';
            app.locate_ct = uibutton(app.Panel, 'push');
            app.locate_ct.ButtonPushedFcn = createCallbackFcn(app, @locate_ctButtonPushed, true);
            app.locate_ct.Text = 'Locate CT';
            app.path_ct = uieditfield(app.Panel, 'text');
            app.path_ct.Value = '/path/to/CT_coronal.tif';
            app.locate_atlas = uibutton(app.Panel, 'push');
            app.locate_atlas.ButtonPushedFcn = createCallbackFcn(app, @locate_atlasButtonPushed, true);
            app.locate_atlas.Text = 'Locate atlas folder';
            app.path_atlas = uieditfield(app.Panel, 'text');
            app.path_atlas.Value = '/path/to/MRIAtlas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = REGISTER_CT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end