classdef add_atlas_info_and_save < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        locate_atlas  matlab.ui.control.Button
        path_atlas    matlab.ui.control.EditField
        TextArea      matlab.ui.control.TextArea
        GoButton      matlab.ui.control.Button
        in_striatum   matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        localize_main % the main localization app
        output = struct; % struct to store info 
    end
    
    methods (Access = private)
        
        function atlas_info(app)
            % turn off warnings
            warning('off','imageio:tiffmexutils:libtiffWarning')
            warning('off','imageio:tiffmexutils:libtiffErrorAsWarning')
            warning('off','imageio:tifftagsread:expectedTagDataFormatMultiple')
            warning('off','MATLAB:polyshape:repairedBySimplify')
            
            % atlas info
            MRIdir = app.path_atlas.Value;
            ccfPath = fullfile(MRIdir,'CCF','annotation_10_coronal.tif');                        
            ccf_key = load(fullfile(MRIdir,'CCF','ccf_key.mat'));
            chonPath = fullfile(MRIdir,'Chon','Chon_labels_coronal.tif');
            chon_key = load(fullfile(MRIdir,'Chon','chon_key.mat'));
            % if we're adding a field for whether fiber is in striatum
            if app.in_striatum.Value == 1
                ccfPath_mask = fullfile(MRIdir,'CCF','all_striatum_mask_10_coronal.tif');
                chonPath_mask = fullfile(MRIdir,'Chon','Chon_all_striatum_mask.tif');
                
                % let's make a striatum filter
                ccf_is_str = ccf_key.labels.parent_value == 477 | ...
                    ccf_key.labels.parent_value == 485 | ...
                    ccf_key.labels.parent_value == 493 | ...
                    ccf_key.labels.value == 477 | ...
                    ccf_key.labels.value == 485 |...
                    ccf_key.labels.value == 493;
                ccf_str_values = ccf_key.labels.value(ccf_is_str);
                chon_is_str = chon_key.labels.ParentID == 477 | ...
                    chon_key.labels.ParentID == 485 | ...
                    chon_key.labels.ParentID == 493 | ...
                    chon_key.labels.StructuralID == 477 | ...
                    chon_key.labels.StructuralID == 485 |...
                    chon_key.labels.StructuralID == 493 | ...
                    contains(chon_key.labels.Franklin_PaxinosFullName,'riatum') |...
                    contains(chon_key.labels.Franklin_PaxinosFullName,'ccumbens') | ...
                    contains(chon_key.labels.Franklin_PaxinosFullName,'utamen');
                chon_str_values = chon_key.labels.StructuralID(chon_is_str);
            end

            % now loop over
            for f = 1:size(app.output.fiber_table,1)
                if app.output.fiber_table.bottom_ML_idx(f) > 0 &&...
                        app.output.fiber_table.bottom_AP_idx(f) > 0 &&...
                        app.output.fiber_table.bottom_DV_idx(f) > 0 
                    % load the relevant slices
                    ccf_slice = tiffreadVolume(ccfPath,...
                        'PixelRegion', {[1 inf], [1 inf],...
                        [app.output.fiber_table.bottom_AP_idx(f) app.output.fiber_table.bottom_AP_idx(f)]});                   
                    ccf_ap = ccf_key.AP.AP(ccf_key.AP.slice==app.output.fiber_table.bottom_AP_idx(f));
                    ccf_ml = ccf_key.ML.ML(ccf_key.ML.slice==app.output.fiber_table.bottom_ML_idx(f));
                    ccf_dv = ccf_key.DV.DV(ccf_key.DV.slice==app.output.fiber_table.bottom_DV_idx(f));
                    ccf_dv2 = ccf_key.DV.DV2(ccf_key.DV.slice==app.output.fiber_table.bottom_DV_idx(f));
                    [~,chon_nearest_ap] = min(abs(chon_key.AP.AP-ccf_ap));
                    chon_slice = tiffreadVolume(chonPath,...
                        'PixelRegion', {[1 inf], [1 inf],...
                        [chon_nearest_ap chon_nearest_ap]});                  
                    
                    % now let's extract the atlas value
                    ccf_ID = ccf_slice(app.output.fiber_table.bottom_DV_idx(f),...
                        app.output.fiber_table.bottom_ML_idx(f));
                    chon_ID = chon_slice(app.output.fiber_table.bottom_DV_idx(f),...
                        app.output.fiber_table.bottom_ML_idx(f));
                    ccf_label = ccf_key.labels.name(ccf_key.labels.value==ccf_ID);
                    chon_label_fp = chon_key.labels.Franklin_PaxinosFullName(chon_key.labels.StructuralID==chon_ID);
                    chon_label_fp_abbrev = chon_key.labels.Franklin_PaxinosAbbreviation(chon_key.labels.StructuralID==chon_ID);
                    chon_label_allen = chon_key.labels.AllenFullName(chon_key.labels.StructuralID==chon_ID);
                    chon_label_allen_abbrev = chon_key.labels.AllenAbbreviation(chon_key.labels.StructuralID==chon_ID);
                    
                    % fill in table with coordinates (AP0 slice = 525)        
                    app.output.fiber_table.bottom_AP(f) = ccf_ap;
                    app.output.fiber_table.bottom_ML(f) = ccf_ml;
                    % estimate atlas DV: these numbers came from landmarks 
                    % along AP=0/ML=0 and matching to Franklin/Paxinos
                    % atlas, and thus does incorporate some scaling between 
                    % the MRI and Franklin/Paxinos atlas
                    app.output.fiber_table.bottom_DV(f) = ccf_dv;
                    % this estimate doesn't account for scaling between MRI
                    % atlas and Franklin/Paxinos, but rather just measures
                    % distance between estimated bregma and recording
                    % location
                    app.output.fiber_table.bottom_DV2(f) = ccf_dv2;
                    
                    if app.in_striatum.Value == 1                        
                        % boolean: is it in the striatum?
                        app.output.fiber_table.in_striatum(f) = ismember(ccf_ID,ccf_str_values) | ismember(chon_ID,chon_str_values);
                    end
                     
                    % fill in table with labels
                    app.output.fiber_table.ccf_ID(f) = ccf_ID;
                    app.output.fiber_table.chon_ID(f) = chon_ID;
                    if ~isempty(ccf_label)
                        app.output.fiber_table.ccf_label(f) = ccf_label;
                    end
                    if ~isempty(chon_label_allen)
                        app.output.fiber_table.chon_allen_label(f) = chon_label_allen;
                        app.output.fiber_table.chon_allen_label_abbrev(f) = chon_label_allen_abbrev;
                    end
                    if ~isempty(chon_label_fp)
                        app.output.fiber_table.chon_fp_label(f) = chon_label_fp;
                        app.output.fiber_table.chon_fp_label_abbrev(f) = chon_label_fp_abbrev;
                    end
                end
            end
        end       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, localize_main_app, output)
            app.localize_main = localize_main_app; % the app info from the main localization
            app.output = output; % get the output from the main app
        end

        % Button pushed function: locate_atlas
        function locate_atlasButtonPushed(app, event)
            filepath = uigetdir;
            app.path_atlas.Value = filepath;
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            disp('loading atlases and adding info')
            
            % disable buttons            
            app.locate_atlas.Enable = 'off';
            app.path_atlas.Enable = 'off';
            app.in_striatum.Enable = 'off';
            pause(0.3);
            
            % atlas_info            
            atlas_info(app)
            
            % save out don't need to save out the CT - we'll just reload that every time
            prog_output = app.output;
            if isfield(prog_output,'ct')
                prog_output = rmfield(prog_output,'ct'); 
            end                
            save(fullfile(app.output.info.savepath,[app.output.info.ctname '_loc_inprog.mat']),'-struct','prog_output')            
            movefile(fullfile(app.output.info.savepath,[app.output.info.ctname '_loc_inprog.mat']),...
                fullfile(app.output.info.savepath,[app.output.info.ctname '_localized_fibers.mat']))        
            
            % update and send back to main app
            update_localization(app.localize_main,app.output,'atlas info app closed')            
            delete(app)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            % if applicable, remove 'tmp' field
            if isfield(app.output,'tmp')
                app.output = rmfield(app.output,'tmp');
            end
            
            % update and send back to main app
            update_localization(app.localize_main,app.output,'atlas info app closed')
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.locate_atlas = uibutton(app.UIFigure, 'push');
            app.locate_atlas.ButtonPushedFcn = createCallbackFcn(app, @locate_atlasButtonPushed, true);
            app.locate_atlas.Text = 'Locate atlas folder';
            app.path_atlas = uieditfield(app.UIFigure, 'text');
            app.path_atlas.Value = '/path/to/MRIAtlas';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Atlas information will be added, and the final file with fiber information and localization coordinates will be saved in a table. '; ''; 'Locate the atlas folder. There''s an option here whether to include a field "in_striatum" in the resulting table indicating whether the fiber recording location is in the striatum (1) or not (0). '; ''; 'The app will close when finished.'};
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Add atlas info and save final fiber localization information';
            app.in_striatum = uicheckbox(app.UIFigure);
            app.in_striatum.Text = 'add "in striatum" field';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = add_atlas_info_and_save(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end