classdef check_multifibers < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MultifiberLabel            matlab.ui.control.Label
        MultifiberListBox          matlab.ui.control.ListBox
        FiberTopLabel              matlab.ui.control.Label
        FiberTopListBox            matlab.ui.control.ListBox
        RecordingLocationTipPanel  matlab.ui.container.Panel
        APzEditFieldLabel          matlab.ui.control.Label
        AP                         matlab.ui.control.NumericEditField
        MLxEditFieldLabel          matlab.ui.control.Label
        ML                         matlab.ui.control.NumericEditField
        DVyEditFieldLabel          matlab.ui.control.Label
        DV                         matlab.ui.control.NumericEditField
        ResetButton                matlab.ui.control.Button
        EnterButton                matlab.ui.control.Button
        CloseButton                matlab.ui.control.Button
        TextArea                   matlab.ui.control.TextArea
        SelectedFiberOnly          matlab.ui.control.CheckBox
        AtlasIndicesPanel          matlab.ui.container.Panel
        tipML                      matlab.ui.control.NumericEditField
        tipDV                      matlab.ui.control.NumericEditField
        tipAP                      matlab.ui.control.NumericEditField
        topAPlabel                 matlab.ui.control.Label
        topAP                      matlab.ui.control.NumericEditField
        topMLlabel                 matlab.ui.control.Label
        topML                      matlab.ui.control.NumericEditField
        topDVlabel                 matlab.ui.control.Label
        topDV                      matlab.ui.control.NumericEditField
        toplabel                   matlab.ui.control.EditField
        tiplabel                   matlab.ui.control.EditField
        axes1                      matlab.ui.control.UIAxes
        axes2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        localize_main % the main localization app
        output = struct; % struct to store info 
    end
    
    methods (Access = private)
        
        
        function show_fibers(app)
            
            % clear axes
            cla(app.axes1)
            cla(app.axes2)
            
            
            % some information and setup
            bottom_num = str2double(app.output.tmp.current_fibnum(strfind(app.output.tmp.current_fibnum,'fibnum')+6:end));
            this_box = app.output.fiber_bottoms.boxes(bottom_num,:); 
            fib_voxels = app.output.fiber_bottoms.voxels{bottom_num};
            [dv,ml,ap] = ind2sub(app.output.info.ct_size,fib_voxels);
            [dv_box,ml_box,ap_box] = ct2box(app,dv,ml,ap);
            n_tops = numel(app.output.tmp.tops);
            fiber_lines = app.output.fiber_bottoms.multi_fiber_checks.(app.output.tmp.current_fibnum).fibers;
            fiber_ends = fiber_ends_struct(app);

            % color map: default to "lines" for 7 or fewer fibers,
            % otherwise turbo
            if numel(app.output.tmp.tops)>7
                colors = turbo(numel(app.output.tmp.tops));
            else
                colors = lines(numel(app.output.tmp.tops));
            end
            
            % plot all voxels in gray on first axes
            %plot3(app.axes1,ml_box,dv_box,ap_box,'.','Color',[.6 .6 .6 0])
            scatter3(app.axes1,ml_box,dv_box,ap_box,3,[.6 .6 .6],'MarkerFaceColor',[.6 .6 .6],'MarkerEdgeColor','none','MarkerFaceAlpha',.3)
            hold(app.axes1,'on')

%             % plot all voxels in gray on first axes
%             fib_shape = alphaShape(ml_box,dv_box,ap_box);            
%             plot(fib_shape,'Parent',app.axes1,'FaceColor',[.5 .5 .5],'FaceAlpha',.3,'LineStyle','none')
%             hold(app.axes1,'on')
            
            % not plot the centroids and endpoints (both axes)
            for i = 1:n_tops
                f = find(app.output.tmp.all_tops == app.output.tmp.tops(i));
                this_line = fiber_lines.(['line' num2str(f)]);
                these_ends = fiber_ends.(['line' num2str(f)]);
                ccolor = [.6 .6 .6];
                msize = 10;
                if app.output.tmp.current_top == app.output.tmp.tops(i)
                    msize = 20;
                end
                if app.SelectedFiberOnly.Value == 0 || app.output.tmp.current_top == app.output.tmp.tops(i) % either we're plotting all of them or it's the selected one
                    ccolor = colors(i,:);                                      
                    % plot centroids: order = ML DV AP
                    plot3(app.axes1,this_line(:,2),this_line(:,3),this_line(:,1),'o','Color',colors(i,:))  
                    try % plot ends with bigger circle: order = ML DV AP
                        plot3(app.axes1,these_ends(:,2),these_ends(:,3),these_ends(:,1),'o','Color',colors(i,:),...
                            'MarkerSize',msize,'LineWidth',2)
                    catch exception
                    end
                end
                % plot centroids: order = ML DV AP
                plot3(app.axes2,this_line(:,2),this_line(:,3),this_line(:,1),'o','Color',ccolor)  
                hold (app.axes2,'on')
                try % plot ends with bigger circle: order = ML DV AP                    
                    plot3(app.axes2,these_ends(:,2),these_ends(:,3),these_ends(:,1),'o','Color',ccolor,...
                        'MarkerSize',msize,'LineWidth',2)
                catch exception
                end
            end
      
            
            % fill a square on dorsal plane and label axes
            for a = 1:2                    
                fill3(app.(['axes' num2str(a)]),...
                    [1 this_box(4)+1 this_box(4)+1 1 1],...
                    [1 1 1 1 1],...
                    [1 1 1+this_box(6) 1+this_box(6) 1],[.6 .6 .6],...
                    'LineStyle','-','EdgeColor','k','FaceAlpha',.5)
                % label
                xlabel(app.(['axes' num2str(a)]),'ML (x)')
                ylabel(app.(['axes' num2str(a)]),'DV (y)')
                zlabel(app.(['axes' num2str(a)]),'AP (z)')
                % title (in the selected color)
                title(app.(['axes' num2str(a)]),[app.output.tmp.current_fibnum ': ' num2str(app.output.tmp.current_top)],...
                    'Color',colors(app.output.tmp.tops==app.output.tmp.current_top,:));
            end 
%             linkprop([app.axes1,app.axes2],{'CameraPosition','CameraUpVector'}); % link for convenience
        end
        
        function [dv, ml, ap] = box2ct(app,dv_box,ml_box,ap_box) % translate small box coordinates to CT coordinates
            dv = [];
            ml = [];
            ap = [];
            bottom_num = str2double(app.output.tmp.current_fibnum(strfind(app.output.tmp.current_fibnum,'fibnum')+6:end));
            this_box = app.output.fiber_bottoms.boxes(bottom_num,:);            
            if ~isempty(dv_box)
                dv = dv_box+this_box(2)-1;
            end
            if ~isempty(ml_box)
                ml = ml_box+this_box(1)-1;
            end
            if ~isempty(ap_box)
                ap = ap_box+this_box(3)-1;
            end
                        
        end
        
        function [dv_box,ml_box,ap_box] = ct2box(app,dv,ml,ap) % translate CT coordinates to small box coordinates
            dv_box = [];
            ml_box = [];
            ap_box = [];
            bottom_num = str2double(app.output.tmp.current_fibnum(strfind(app.output.tmp.current_fibnum,'fibnum')+6:end));
            this_box = app.output.fiber_bottoms.boxes(bottom_num,:);
            if ~isempty(dv)
                dv_box = dv-this_box(2)+1;
            end
            if ~isempty(ml)
                ml_box = ml-this_box(1)+1;
            end
            if ~isempty(ap)
                ap_box = ap-this_box(3)+1;
            end
                    
        end
        
        function fiber_ends = fiber_ends_struct(app)
            fib_tops = app.output.tmp.tops;
            fiber_ends = struct;
            for i = 1:numel(fib_tops)
                f = find(app.output.tmp.all_tops == app.output.tmp.tops(i));
                this_fib_top = fib_tops(i);
                tbl_idx = find(app.output.fiber_table.top_ID == this_fib_top);
                [dv_top,ml_top,ap_top] = ct2box(app,app.output.fiber_table.top_DV_idx(tbl_idx),...
                    app.output.fiber_table.top_ML_idx(tbl_idx),app.output.fiber_table.top_AP_idx(tbl_idx));
                [dv_tip,ml_tip,ap_tip] = ct2box(app,app.output.fiber_table.bottom_DV_idx(tbl_idx),...
                    app.output.fiber_table.bottom_ML_idx(tbl_idx),app.output.fiber_table.bottom_AP_idx(tbl_idx));
                these_coord = [ap_tip ml_tip dv_tip; ap_top ml_top dv_top]; 
                fiber_ends.(['line' num2str(f)]) = these_coord;
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, localize_main_app, output)
            app.localize_main = localize_main_app; % the app info from the main localization
            app.output = output; % get the output from the main app
            
            if isempty(output.fiber_bottoms.fiber_lists.fiber_multiple_tops)
                 % if applicable, remove 'tmp' field
                if isfield(app.output,'tmp')
                    app.output = rmfield(app.output,'tmp');
                end
                % close and send back to main app
                update_localization(app.localize_main,app.output,'lucky you, no multifibers! app for checking multifibers closed.')
                delete(app)
            else
                % get multifiber numbers and top numbers
                app.output.tmp.fibnums = fieldnames(app.output.fiber_bottoms.multi_fiber_checks);
                app.output.tmp.current_fibnum = app.output.tmp.fibnums{1};
                app.output.tmp.all_tops = structfun(@(x) x,app.output.fiber_bottoms.multi_fiber_checks.(app.output.tmp.current_fibnum).top_nums);
                app.output.tmp.tops = app.output.tmp.all_tops(app.output.tmp.all_tops>0);            
                app.output.tmp.current_top = app.output.tmp.tops(1);
                
                % fill in the listboxes
                app.MultifiberListBox.Items = app.output.tmp.fibnums;           
                app.MultifiberListBox.Value = app.output.tmp.current_fibnum;
                app.FiberTopListBox.Items = cellstr(num2str(app.output.tmp.tops));
                app.FiberTopListBox.Value = num2str(app.output.tmp.current_top);
                
                % update recording location
                tbl_idx = find(app.output.fiber_table.top_ID == app.output.tmp.current_top);
                [dv_box,ml_box,ap_box] = ct2box(app,...
                    app.output.fiber_table.bottom_DV_idx(tbl_idx),...
                    app.output.fiber_table.bottom_ML_idx(tbl_idx),...
                    app.output.fiber_table.bottom_AP_idx(tbl_idx));             
                app.DV.Value = dv_box;
                app.ML.Value = ml_box;
                app.AP.Value = ap_box;
                
                % update tip/top atlas indices location
                app.tipAP.Value = app.output.fiber_table.bottom_AP_idx(tbl_idx);
                app.tipML.Value = app.output.fiber_table.bottom_ML_idx(tbl_idx);
                app.tipDV.Value = app.output.fiber_table.bottom_DV_idx(tbl_idx);
                app.topAP.Value = app.output.fiber_table.top_AP_idx(tbl_idx);
                app.topML.Value = app.output.fiber_table.top_ML_idx(tbl_idx);
                app.topDV.Value = app.output.fiber_table.top_DV_idx(tbl_idx);
                
                % enable data cursor
                dcm_obj = datacursormode(app.UIFigure);
                dcm_obj.Enable = 'on';
                
                % show the fibers
                show_fibers(app);
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            % if applicable, remove 'tmp' field
            if isfield(app.output,'tmp')
                app.output = rmfield(app.output,'tmp');
            end
            % update and send back to main app
            update_localization(app.localize_main,app.output,'app for checking multifibers closed')
            delete(app)
        end

        % Value changed function: SelectedFiberOnly
        function SelectedFiberOnlyValueChanged(app, event)
            show_fibers(app)
        end

        % Value changed function: MultifiberListBox
        function MultifiberListBoxValueChanged(app, event)
            app.output.tmp.current_fibnum = app.MultifiberListBox.Value;
            app.output.tmp.all_tops = structfun(@(x) x,app.output.fiber_bottoms.multi_fiber_checks.(app.output.tmp.current_fibnum).top_nums);
            app.output.tmp.tops = app.output.tmp.all_tops(app.output.tmp.all_tops>0);            
            app.output.tmp.current_top = app.output.tmp.tops(1);
            app.FiberTopListBox.Items = cellstr(num2str(app.output.tmp.tops));
            app.FiberTopListBox.Value = num2str(app.output.tmp.current_top);
            % update recording location
            tbl_idx = find(app.output.fiber_table.top_ID == app.output.tmp.current_top);
            [dv_box,ml_box,ap_box] = ct2box(app,...
                app.output.fiber_table.bottom_DV_idx(tbl_idx),...
                app.output.fiber_table.bottom_ML_idx(tbl_idx),...
                app.output.fiber_table.bottom_AP_idx(tbl_idx));             
            app.DV.Value = dv_box;
            app.ML.Value = ml_box;
            app.AP.Value = ap_box;
            % update tip/top atlas indices location
            app.tipAP.Value = app.output.fiber_table.bottom_AP_idx(tbl_idx);
            app.tipML.Value = app.output.fiber_table.bottom_ML_idx(tbl_idx);
            app.tipDV.Value = app.output.fiber_table.bottom_DV_idx(tbl_idx);
            app.topAP.Value = app.output.fiber_table.top_AP_idx(tbl_idx);
            app.topML.Value = app.output.fiber_table.top_ML_idx(tbl_idx);
            app.topDV.Value = app.output.fiber_table.top_DV_idx(tbl_idx);
            % update plot
            show_fibers(app)       
        end

        % Value changed function: FiberTopListBox
        function FiberTopListBoxValueChanged(app, event)
            app.output.tmp.current_top = str2double(app.FiberTopListBox.Value);
            % update recording location
            tbl_idx = find(app.output.fiber_table.top_ID == app.output.tmp.current_top);
            [dv_box,ml_box,ap_box] = ct2box(app,...
                app.output.fiber_table.bottom_DV_idx(tbl_idx),...
                app.output.fiber_table.bottom_ML_idx(tbl_idx),...
                app.output.fiber_table.bottom_AP_idx(tbl_idx));             
            app.DV.Value = dv_box;
            app.ML.Value = ml_box;
            app.AP.Value = ap_box;
             % update tip/top atlas indices location
            app.tipAP.Value = app.output.fiber_table.bottom_AP_idx(tbl_idx);
            app.tipML.Value = app.output.fiber_table.bottom_ML_idx(tbl_idx);
            app.tipDV.Value = app.output.fiber_table.bottom_DV_idx(tbl_idx);
            app.topAP.Value = app.output.fiber_table.top_AP_idx(tbl_idx);
            app.topML.Value = app.output.fiber_table.top_ML_idx(tbl_idx);
            app.topDV.Value = app.output.fiber_table.top_DV_idx(tbl_idx);
            % update plot
            show_fibers(app)
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            [dv,ml,ap] = box2ct(app,app.DV.Value,app.ML.Value,app.AP.Value);
            app.output.fiber_table.bottom_AP_idx(app.output.fiber_table.top_ID == app.output.tmp.current_top) = ap;            
            app.output.fiber_table.bottom_ML_idx(app.output.fiber_table.top_ID == app.output.tmp.current_top) = ml;
            app.output.fiber_table.bottom_DV_idx(app.output.fiber_table.top_ID == app.output.tmp.current_top) = dv;           
            show_fibers(app)   
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % reset table back to original based on the fiber detection
            fib_top_idx = find(structfun(@(x) (x),app.output.fiber_bottoms.multi_fiber_checks.(app.output.tmp.current_fibnum).top_nums)==app.output.tmp.current_top);
            these_ends = app.output.fiber_bottoms.multi_fiber_checks.(app.output.tmp.current_fibnum).ends.(['line' num2str(fib_top_idx)]);
            ap_box = these_ends(1,1);
            ml_box = these_ends(1,2);
            dv_box = these_ends(1,3);
            [dv,ml,ap] = box2ct(app,dv_box,ml_box,ap_box);
            tbl_idx = find(app.output.fiber_table.top_ID == app.output.tmp.current_top);
            app.output.fiber_table.bottom_DV_idx(tbl_idx) = dv;
            app.output.fiber_table.bottom_ML_idx(tbl_idx) = ml;
            app.output.fiber_table.bottom_AP_idx(tbl_idx) = ap;
            % update current string
            app.DV.Value = dv_box;
            app.ML.Value = ml_box;
            app.AP.Value = ap_box;
            % show 
            show_fibers(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MultifiberLabel = uilabel(app.UIFigure);
            app.MultifiberLabel.Text = 'Multi-fiber #';
            app.MultifiberListBox = uilistbox(app.UIFigure);
            app.MultifiberListBox.Items = {'fibnum1', 'fibnum2', 'fibnum3', 'fibnum4', 'fibnum5', ''};
            app.MultifiberListBox.ValueChangedFcn = createCallbackFcn(app, @MultifiberListBoxValueChanged, true);
            app.MultifiberListBox.Value = 'fibnum1';
            app.FiberTopLabel = uilabel(app.UIFigure);
            app.FiberTopLabel.Text = 'Fiber Top #';
            app.FiberTopListBox = uilistbox(app.UIFigure);
            app.FiberTopListBox.Items = {'1', '2', '3', '4', '5'};
            app.FiberTopListBox.ValueChangedFcn = createCallbackFcn(app, @FiberTopListBoxValueChanged, true);
            app.FiberTopListBox.Value = '1';
            app.RecordingLocationTipPanel = uipanel(app.UIFigure);
            app.RecordingLocationTipPanel.Title = 'Recording Location (Tip)';
            app.APzEditFieldLabel = uilabel(app.RecordingLocationTipPanel);
            app.APzEditFieldLabel.Text = 'AP (z)';
            app.AP = uieditfield(app.RecordingLocationTipPanel, 'numeric');
            app.MLxEditFieldLabel = uilabel(app.RecordingLocationTipPanel);
            app.MLxEditFieldLabel.Text = 'ML (x)';
            app.ML = uieditfield(app.RecordingLocationTipPanel, 'numeric');
            app.DVyEditFieldLabel = uilabel(app.RecordingLocationTipPanel);
            app.DVyEditFieldLabel.Text = 'DV (y)';
            app.DV = uieditfield(app.RecordingLocationTipPanel, 'numeric');
            app.ResetButton = uibutton(app.RecordingLocationTipPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.EnterButton = uibutton(app.RecordingLocationTipPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Manually verify the the multifibers have been properly separated. Use the list box on the left to select a multifiber, and use the list box on the right to select one fiber at a time within the multifiber to show the recording locations.The left plot shows (in gray) all the voxels in the fibers, with the slice centroids plotted in colors. The right shows just the slice centroids. The gray box indicates the plane in which the tops were identified, i.e., the dorsal end.  Rotate the axes around, and use the data cursors to examine these plots. The selected fiber will have larger endpoint markers, and the title will also indicate the color and identify of the selected fiber. You can also check the box to show just the selcted fiber. '; ''; 'The assigned recording location (the ventral end) will display below in the text fields, and you may manually edit those. Click enter to save them. You can also reset any of the fibers to the original settings. Note that the coordinates displayed in the editable Recording Location (Tip) panel aren''t the actual coordinates, just the adjusted ones for the section shown. The actual coordinates are shown in the Atlas Indices panel: these may be helpful for manual verification in an external image viewing program (e.g., ImageJ, 3DSlicer, etc).'; ''};
            app.SelectedFiberOnly = uicheckbox(app.UIFigure);
            app.SelectedFiberOnly.ValueChangedFcn = createCallbackFcn(app, @SelectedFiberOnlyValueChanged, true);
            app.SelectedFiberOnly.Text = 'Show only selected fiber';
            app.AtlasIndicesPanel = uipanel(app.UIFigure);
            app.AtlasIndicesPanel.Title = 'Atlas Indices';
            app.tipML = uieditfield(app.AtlasIndicesPanel, 'numeric');
            app.tipML.Editable = 'off';
            app.tipDV = uieditfield(app.AtlasIndicesPanel, 'numeric');
            app.tipDV.Editable = 'off';
            app.tipAP = uieditfield(app.AtlasIndicesPanel, 'numeric');
            app.tipAP.Editable = 'off';
            app.topAPlabel = uilabel(app.AtlasIndicesPanel);
            app.topAPlabel.Text = 'AP (z)';
            app.topAP = uieditfield(app.AtlasIndicesPanel, 'numeric');
            app.topMLlabel = uilabel(app.AtlasIndicesPanel);
            app.topMLlabel.Text = 'ML (x)';
            app.topML = uieditfield(app.AtlasIndicesPanel, 'numeric');
            app.topDVlabel = uilabel(app.AtlasIndicesPanel);
            app.topDVlabel.Text = 'DV (y)';
            app.topDV = uieditfield(app.AtlasIndicesPanel, 'numeric');
            app.toplabel = uieditfield(app.AtlasIndicesPanel, 'text');
            app.toplabel.Editable = 'off';
            app.toplabel.Value = 'Top';
            app.tiplabel = uieditfield(app.AtlasIndicesPanel, 'text');
            app.tiplabel.Editable = 'off';
            app.tiplabel.Value = 'Tip';
            app.axes1 = uiaxes(app.UIFigure);
            xlabel(app.axes1, 'ML')
            ylabel(app.axes1, 'DV')
            zlabel(app.axes1, {'AP'; ''})
            app.axes1.XTick = [];
            app.axes1.YTick = [];
            app.axes1.ZTick = [];
            app.axes2 = uiaxes(app.UIFigure);
            xlabel(app.axes2, 'ML')
            ylabel(app.axes2, 'DV')
            zlabel(app.axes2, {'AP'; ''})
            app.axes2.XTick = [];
            app.axes2.YTick = [];
            app.axes2.ZTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = check_multifibers(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end