classdef fiber_area_ID < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        drawpolygonButton  matlab.ui.control.Button
        resetButton        matlab.ui.control.Button
        DONEButton         matlab.ui.control.Button
        TextArea           matlab.ui.control.TextArea
        axes_axial         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        localize_main % the main localization app
        output = struct; % struct to store info
    end
    
    methods (Access = private)
        
        
        function view_axial_area(app)
            % max projection
            axial_max_proj = transpose(squeeze(max(app.output.ct(app.output.fiber_tops.axial_slice_num:end,:,:))));
            % mask it
            axial_polymask = roipoly(axial_max_proj,app.output.fiber_bottoms.axial_proj.polygon(:,1),app.output.fiber_bottoms.axial_proj.polygon(:,2));
            axial_max_proj(~axial_polymask) = 0;                        
            % now show
            imagesc(app.axes_axial,axial_max_proj);
            set(app.axes_axial,'XTick',[],'YTick',[],'YDir','reverse',...
                'XLim',[min(app.output.fiber_bottoms.axial_proj.polygon(:,1)) max(app.output.fiber_bottoms.axial_proj.polygon(:,1))],...
                'YLim',[min(app.output.fiber_bottoms.axial_proj.polygon(:,2)) max(app.output.fiber_bottoms.axial_proj.polygon(:,2))]);            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, localize_main_app, output)
            app.localize_main = localize_main_app; % the app info from the main localization
            app.output = output; % get the output from the main app
            
            if ~isfield(app.output,'fibers') || ~isfield(app.output.fiber_bottoms,'axial_proj') || ~isfield(app.output.fiber_bottoms.axial_proj,'polygon')
                % if there is no polygon, it's just axial slice boundaries
                app.output.fiber_bottoms.axial_proj.polygon = [1 1;...
                    size(app.output.fiber_tops.axial_slice,2) 1;...
                    size(app.output.fiber_tops.axial_slice,2) size(app.output.fiber_tops.axial_slice,1);...
                    1 size(app.output.fiber_tops.axial_slice,1)];                       
            end
            view_axial_area(app)

        end

        % Button pushed function: DONEButton
        function DONEButtonPushed(app, event)
            % make 3D polymask
            app.output.fiber_bottoms.axial_proj.polymask = roipoly(zeros(app.output.info.ct_size(3),app.output.info.ct_size(2)),...
                app.output.fiber_bottoms.axial_proj.polygon(:,1),app.output.fiber_bottoms.axial_proj.polygon(:,2));
            app.output.fiber_bottoms.axial_proj.polymask = permute(repmat(app.output.fiber_bottoms.axial_proj.polymask,1,1,app.output.info.ct_size(1)),[3 2 1]);
            % if applicable, remove 'tmp' field
            if isfield(app.output,'tmp')
                app.output = rmfield(app.output,'tmp');
            end
            % update and send back to main app
            update_localization(app.localize_main,app.output,'fiber area identification app finished')
            delete(app)
        end

        % Button pushed function: drawpolygonButton
        function drawpolygonButtonPushed(app, event)
            this_poly = drawpolygon(app.axes_axial);
            app.output.fiber_bottoms.axial_proj.polygon = this_poly.Position;            
            
            % update
            view_axial_area(app)
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.output.fiber_bottoms.axial_proj.polygon = [1 1;...
                size(app.output.fiber_tops.axial_slice,2) 1;...
                size(app.output.fiber_tops.axial_slice,2) size(app.output.fiber_tops.axial_slice,1);...
                1 size(app.output.fiber_tops.axial_slice,1)];
            view_axial_area(app)      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.drawpolygonButton = uibutton(app.UIFigure, 'push');
            app.drawpolygonButton.ButtonPushedFcn = createCallbackFcn(app, @drawpolygonButtonPushed, true);
            app.drawpolygonButton.Text = 'draw polygon';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.DONEButton = uibutton(app.UIFigure, 'push');
            app.DONEButton.ButtonPushedFcn = createCallbackFcn(app, @DONEButtonPushed, true);
            app.DONEButton.Text = 'DONE';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Let''s isolate the area containing the fibers. On the left is an axial max projection. Draw a polygon to zoom in and isolate the area with fibers. Click "draw polygon" and then draw a polygon around the area, double-clicking to complete. You can repeat this as many times as needed to zoom in. To reset to the original view, click reset.'};
            app.axes_axial = uiaxes(app.UIFigure);
            title(app.axes_axial, 'axial slice')
            zlabel(app.axes_axial, 'Z')
            app.axes_axial.XTick = [];
            app.axes_axial.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fiber_area_ID(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end