classdef fiber_top_ID < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        instructions                  matlab.ui.control.TextArea
        DONEButton                    matlab.ui.control.Button
        ManualContrast                matlab.ui.container.Panel
        min_slider                    matlab.ui.control.Slider
        max_slider                    matlab.ui.control.Slider
        minLabel                      matlab.ui.control.Label
        maxLabel                      matlab.ui.control.Label
        hist_axes                     matlab.ui.control.UIAxes
        AutoContrast                  matlab.ui.container.ButtonGroup
        raw_button                    matlab.ui.control.ToggleButton
        imadjust_button               matlab.ui.control.ToggleButton
        histeq_button                 matlab.ui.control.ToggleButton
        adapthisteq_button            matlab.ui.control.ToggleButton
        SlicePanel                    matlab.ui.container.Panel
        sliceEditFieldLabel           matlab.ui.control.Label
        slice_num                     matlab.ui.control.NumericEditField
        PreviousButton                matlab.ui.control.Button
        NextButton                    matlab.ui.control.Button
        Panel_identified_tops         matlab.ui.container.Panel
        topsidentifiedEditFieldLabel  matlab.ui.control.Label
        n_tops                        matlab.ui.control.NumericEditField
        identifytopsButton            matlab.ui.control.Button
        threshEditFieldLabel          matlab.ui.control.Label
        thresh_field                  matlab.ui.control.NumericEditField
        minareaEditFieldLabel         matlab.ui.control.Label
        minarea_field                 matlab.ui.control.NumericEditField
        axes_tops                     matlab.ui.control.UIAxes
        Panel_axial_slice             matlab.ui.container.Panel
        drawpolygonButton             matlab.ui.control.Button
        resetButton                   matlab.ui.control.Button
        axes_axial                    matlab.ui.control.UIAxes
        Panel_ref                     matlab.ui.container.Panel
        locate_ref                    matlab.ui.control.Button
        path_ref                      matlab.ui.control.EditField
        reference_txt                 matlab.ui.control.TextArea
        flipyButton                   matlab.ui.control.Button
        transposeButton               matlab.ui.control.Button
        ButtonGroup                   matlab.ui.container.ButtonGroup
        matrixButton                  matlab.ui.control.RadioButton
        coordsButton                  matlab.ui.control.RadioButton
        coords_col1                   matlab.ui.control.NumericEditField
        coords_col2                   matlab.ui.control.NumericEditField
        axes_ref                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        localize_main % the main localization app
        output = struct; % struct to store info        
    end
    
    methods (Access = private)
        
        function axial_image(app)
            
            % axes
            axes(app.axes_axial)
            colormap(app.axes_axial,'parula')
            cla(app.axes_axial)              
            
            % first threshold according to sliders
            
            app.output.fiber_tops.axial_slice_contrast = app.output.fiber_tops.axial_slice;                
            app.output.fiber_tops.axial_slice_contrast = ...
                imadjust(uint16(app.output.fiber_tops.axial_slice_contrast),...
                [app.min_slider.Value app.max_slider.Value]);
            
            % auto-contrast
            if app.imadjust_button.Value == 1
                app.output.fiber_tops.axial_slice_contrast = ...
                    imadjust(app.output.fiber_tops.axial_slice_contrast);
            elseif app.histeq_button.Value == 1
                app.output.fiber_tops.axial_slice_contrast = ...
                    histeq(app.output.fiber_tops.axial_slice_contrast);
            elseif app.adapthisteq_button.Value == 1
                app.output.fiber_tops.axial_slice_contrast = ...
                    adapthisteq(app.output.fiber_tops.axial_slice_contrast);
            end  
            
            % roi definition            
            app.output.fiber_tops.axial_section = app.output.fiber_tops.axial_slice_contrast;
            if ~isempty(app.output.fiber_tops.axial_roi)                
                polymask = roipoly(app.output.fiber_tops.axial_slice_contrast,...
                    app.output.fiber_tops.axial_roi(:,1),...
                    app.output.fiber_tops.axial_roi(:,2));
                app.output.fiber_tops.axial_section(~polymask) = 0;
                if ~isfield(app.output.fiber_tops,'tmp') || ...
                       ~isfield(app.output.fiber_tops.tmp,'axial_corners') 
                    app.output.tmp.axial_corners = [min(app.output.fiber_tops.axial_roi(:,1)),...
                       max(app.output.fiber_tops.axial_roi(:,1));...
                       min(app.output.fiber_tops.axial_roi(:,2)),...
                       max(app.output.fiber_tops.axial_roi(:,2))];...        

                end
                app.output.fiber_tops.axial_section = ...
                    app.output.fiber_tops.axial_section(app.output.tmp.axial_corners(2,1):app.output.tmp.axial_corners(2,2),...
                    app.output.tmp.axial_corners(1,1):app.output.tmp.axial_corners(1,2));
                
            end   
            
            % display
            imagesc(app.axes_axial, app.output.fiber_tops.axial_section)            
            % axes settings
            set(app.axes_axial,'YLim',[0 size(app.output.fiber_tops.axial_section,1)]+1,...
                'XLim',[0 size(app.output.fiber_tops.axial_section,2)+1])
            axis(app.axes_axial,'equal')
        end
        
        function reset_slice(app)
            
            % reset a bunch of stuff
            app.output.fiber_tops.axial_slice = transpose(squeeze(app.output.ct(app.output.fiber_tops.axial_slice_num,:,:)));
            app.output.fiber_tops.axial_slice_contrast = app.output.fiber_tops.axial_slice;
            app.output.fiber_tops.axial_section = app.output.fiber_tops.axial_slice;
            app.output.fiber_tops.axial_roi = [];
            app.output.fiber_tops.tops_mask = [];
            app.output.fiber_tops.centers_AP = [];
            app.output.fiber_tops.centers_ML = [];
            app.output.fiber_tops.threshold_level = [];
            app.output.fiber_tops.area_minimum = [];
            app.output.tmp.axial_corners = [];            
            
            % reset tops 
            cla(app.axes_tops)
            
            % plot histogram
            hist_contrast(app)
            
        end
        
        function hist_contrast(app)
            axes(app.hist_axes)
            cla(app.hist_axes)
            hold(app.hist_axes,'on')
            minmax = (2^16)*[app.min_slider.Value app.max_slider.Value]; % int16
%             minmax = double(max(app.output.fiber_tops.axial_slice(:)))*...
%                 [app.min_slider.Value app.max_slider.Value];
            hg = histogram(app.hist_axes,app.output.fiber_tops.axial_slice(:));
            xline(app.hist_axes,minmax(1),'-r')
            xline(app.hist_axes,minmax(2),'-r')
            set(app.hist_axes,'XTick',[],'YTick',[],...
                'XLim',[0 max(app.output.fiber_tops.axial_slice(:))],...
                'YLim',[0 max(hg.Values)])
           
            % plot
            axial_image(app)            
        end
        
        function identify_tops(app)
             % binarize the axial slice to find connected blobs (adaptive threshold)
            t = adaptthresh(app.output.fiber_tops.axial_slice_contrast); 
            app.output.tmp.top_slice = imbinarize(...
                app.output.fiber_tops.axial_slice_contrast,...
                t*app.thresh_field.Value);
            
            % get rid of blobs outside ROI           
            if ~isempty(app.output.fiber_tops.axial_roi)                
                polymask = roipoly(app.output.fiber_tops.axial_slice_contrast,...
                    app.output.fiber_tops.axial_roi(:,1),...
                    app.output.fiber_tops.axial_roi(:,2));
                app.output.tmp.top_slice(~polymask) = 0;
            end

            % isolate connected blobs and note sizes
            tops = bwconncomp(app.output.tmp.top_slice,4);
            top_sizes = cellfun(@numel,tops.PixelIdxList);
                      
            % get rid of blobs that are too small
            fake_tops = find(top_sizes<app.minarea_field.Value); 
            for i = 1:numel(fake_tops)
                app.output.tmp.top_slice(tops.PixelIdxList{fake_tops(i)}) = 0;
            end
            
            % re-find blobs
            tops = bwconncomp(app.output.tmp.top_slice);
            top_sizes = cellfun(@numel,tops.PixelIdxList);
            
            % make a mask to keep for later
            app.output.fiber_tops.tops_mask = zeros(size(app.output.fiber_tops.axial_slice_contrast));
            for i = 1:numel(tops.PixelIdxList)
                app.output.fiber_tops.tops_mask(tops.PixelIdxList{i}) = i;
            end    
            % extract centroids
            top_centers = transpose(reshape(cell2mat({regionprops(tops,'centroid').Centroid}),2,numel(top_sizes)));
            app.output.fiber_tops.centers_AP = top_centers(:,2);
            app.output.fiber_tops.centers_ML = top_centers(:,1);
            app.output.fiber_tops.threshold_level = app.thresh_field.Value;
            app.output.fiber_tops.area_minimum = app.minarea_field.Value;
            
            
            % update number of identified tops
            app.n_tops.Value = numel(top_sizes);
            
            % update plot     
            axes(app.axes_tops)
            colormap(app.axes_tops,'parula')
            cla(app.axes_tops)
            imagesc(app.axes_tops,double(app.output.fiber_tops.tops_mask>0))
            set(app.axes_tops,'XTick',[],'YTick',[]);
            if isfield(app.output,'tmp') && isfield(app.output.tmp,'axial_corners')
                set(app.axes_tops,'XLim',app.output.tmp.axial_corners(1,:),...
                'YLim',app.output.tmp.axial_corners(2,:));
            else
                set(app.axes_tops,'XLim',get(app.axes_axial,'XLim'),...
                'YLim',get(app.axes_axial,'YLim'));
                axis(app.axes_tops,'equal')
            end
        end
        
        function show_ref(app)
            % first clear axes
            cla(app.axes_ref)
            % if it's an image, show it
            if strcmp(app.output.tmp.this_ref_type,'image')
                imshow(app.output.tmp.this_ref,'Parent',app.axes_ref)
            else % otherwise
                if app.coordsButton.Value == 1 % if coordinates, plot
                    plot(app.axes_ref,app.output.tmp.this_ref(:,app.coords_col1.Value),app.output.tmp.this_ref(:,app.coords_col2.Value),'ok')
                else % if a matrix, imagesc
                    imagesc(app.axes_ref,app.output.tmp.this_ref>0)
                end
            end            
            axis(app.axes_ref,'equal')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, localize_main_app, output)
            app.localize_main = localize_main_app; % the app info from the main localization
            app.output = output; % get the output from the main app
            if ~isfield(app.output,'fiber_tops') || ~isfield(app.output.fiber_tops,'axial_slice_num')
                app.output.fiber_tops.axial_slice_num = round(.25*size(app.output.ct,1));
                app.slice_num.Value = app.output.fiber_tops.axial_slice_num;
            end
            fields_needed = {'axial_slice_num',...
                'axial_slice','axial_slice_contrast','axial_section',...
                'axial_roi'};
            if isfield(app.output,'fiber_tops') &&...
                    sum(ismember(fields_needed,fieldnames(app.output.fiber_tops))) == numel(fields_needed)
                app.slice_num.Value = app.output.fiber_tops.axial_slice_num;
                axial_image(app) 
                % fiber tops
                if isfield(app.output.fiber_tops,'threshold_level')
                    app.thresh_field.Value = app.output.fiber_tops.threshold_level;
                end
                if isfield(app.output.fiber_tops,'area_minimum')
                    app.minarea_field.Value = app.output.fiber_tops.area_minimum;
                end
                if isfield(app.output.fiber_tops,'threshold_level') && ...
                        isfield(app.output.fiber_tops,'area_minimum')
                    identify_tops(app)
                end
            else
                reset_slice(app);
            end         
        end

        % Value changed function: slice_num
        function slice_numValueChanged(app, event)
            app.output.fiber_tops.axial_slice_num = app.slice_num.Value;
            reset_slice(app)            
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.output.fiber_tops.axial_slice_num = app.output.fiber_tops.axial_slice_num-1;
            reset_slice(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.output.fiber_tops.axial_slice_num = app.output.fiber_tops.axial_slice_num+1;
            reset_slice(app);
        end

        % Selection changed function: AutoContrast
        function AutoContrastSelectionChanged(app, event)
            axial_image(app)
            
        end

        % Value changed function: min_slider
        function min_sliderValueChanged(app, event)
            if app.min_slider.Value >= app.max_slider.Value
                app.min_slider.Value = app.max_slider.Value - .01;
            end
            hist_contrast(app)
        end

        % Value changed function: max_slider
        function max_sliderValueChanged(app, event)
            if app.max_slider.Value <= app.min_slider.Value
                app.max_slider.Value = app.min_slider.Value + .01;
            end
            hist_contrast(app)
            
        end

        % Button pushed function: drawpolygonButton
        function drawpolygonButtonPushed(app, event)
            roi = drawpolygon(app.axes_axial);
            x = round(roi.Position(:,1));
            y = round(roi.Position(:,2));
            x(x>size(app.output.fiber_tops.axial_section,2)) = size(app.output.fiber_tops.axial_section,2);
            y(y>size(app.output.fiber_tops.axial_section,1)) = size(app.output.fiber_tops.axial_section,1);
            
            if isempty(app.output.fiber_tops.axial_roi)
                app.output.fiber_tops.axial_roi = [x y];
            else
                app.output.fiber_tops.axial_roi = [app.output.tmp.axial_corners(1,1)+x-1,...
                    app.output.tmp.axial_corners(2,1)+y-1];
            end
            if isempty(app.output.tmp.axial_corners)    
                app.output.tmp.axial_corners = [min(x) max(x); min(y) max(y)];...        
            else
                app.output.tmp.axial_corners(:,1) = app.output.tmp.axial_corners(:,1)+[min(x)-1; min(y)-1];
                app.output.tmp.axial_corners(:,2) = app.output.tmp.axial_corners(:,1)+[range(x)-1;range(y)-1];
            end           
            axial_image(app)
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            reset_slice(app)
        end

        % Button pushed function: identifytopsButton
        function identifytopsButtonPushed(app, event)
            
           identify_tops(app)            
        end

        % Button pushed function: locate_ref
        function locate_refButtonPushed(app, event)
            [filename,filepath] = uigetfile('*.*');
            app.path_ref.Value = fullfile(filepath,filename);
            if endsWith(filename,'.mat')
                app.output.tmp.this_ref_type = 'mat';
                app.output.tmp.this_ref = load(fullfile(filepath,filename));                                
                ref_fields = fieldnames(app.output.tmp.this_ref);
                app.output.tmp.this_ref = app.output.tmp.this_ref.(ref_fields{1});
                app.ButtonGroup.Enable = 'on';
                app.coords_col1.Value = 1;
                app.coords_col2.Value = 2;
            else
                app.output.tmp.this_ref_type = 'image';
                app.output.tmp.this_ref = imread(fullfile(filepath,filename));                
                app.ButtonGroup.Enable = 'off';
            end           
            show_ref(app)
        end

        % Button pushed function: DONEButton
        function DONEButtonPushed(app, event)
            if isfield(app.output,'tmp')
                app.output = rmfield(app.output,'tmp');
            end            
            update_localization(app.localize_main,app.output,'fiber top identification app finished')
            delete(app)
        end

        % Button pushed function: flipyButton
        function flipyButtonPushed(app, event)
            ydirs = {'normal','reverse'};
            set(app.axes_ref,'YDir',ydirs{~ismember(ydirs,get(app.axes_ref,'YDir'))})
        end

        % Button pushed function: transposeButton
        function transposeButtonPushed(app, event)
            if strcmp(app.output.tmp.this_ref_type,'image')
                app.output.tmp.this_ref = permute(app.output.tmp.this_ref,[2 1 3]); % transpose by permuting the first 2 columns
            else
                if app.coordsButton.Value == 1
                    tmp = [app.coords_col1.Value app.coords_col2.Value];
                    app.coords_col1.Value = tmp(2);
                    app.coords_col2.Value = tmp(1);
                else
                    app.output.tmp.this_ref = transpose(app.output.tmp.this_ref);
                end
            end
            show_ref(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            %selectedButton = app.ButtonGroup.SelectedObject;
            if app.coordsButton.Value == 1
                % turn on column selection
                app.coords_col1.Enable = 'on';
                app.coords_col2.Enable = 'on';
                % check that the column values are appropriate
                if app.coords_col1.Value > size(app.output.tmp.this_ref,2)
                    app.coords_col1.Value = size(app.output.tmp.this_ref,2);
                elseif app.coords_col1.Value < 1
                    app.coords_col1.Value = 1;
                end
                % check that the column values are appropriate
                if app.coords_col2.Value > size(app.output.tmp.this_ref,2)
                    app.coords_col2.Value = size(app.output.tmp.this_ref,2);
                elseif app.coords_col2.Value < 1
                    app.coords_col2.Value = 1;
                end
            else
                % turn off column selection
                app.coords_col1.Enable = 'off';
                app.coords_col2.Enable = 'off';
            end
            show_ref(app)
        end

        % Value changed function: coords_col1
        function coords_col1ValueChanged(app, event)
            % if we have a ref and are using coordinates
            if ~isfield(app.output.tmp, 'this_ref') && ...
                    app.coordsButton.Value == 1 
                % check that the values are appropriate
                if app.coords_col1.Value > size(app.output.tmp.this_ref,2)
                    app.coords_col1.Value = size(app.output.tmp.this_ref,2);
                elseif app.coords_col1.Value < 1
                    app.coords_col1.Value = 1;
                end
                show_ref(app)
            end            
        end

        % Value changed function: coords_col2
        function coords_col2ValueChanged(app, event)
            % if we have a ref and are using coordinates
            if ~isfield(app.output.tmp, 'this_ref') && ...
                    app.coordsButton.Value == 1 
                % check that the values are appropriate
                if app.coords_col2.Value > size(app.output.tmp.this_ref,2)
                    app.coords_col2.Value = size(app.output.tmp.this_ref,2);
                elseif app.coords_col2.Value < 1
                    app.coords_col2.Value = 1;
                end
                show_ref(app)
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.instructions = uitextarea(app.UIFigure);
            app.instructions.Editable = 'off';
            app.instructions.Value = {'Shown on the left is an axial slice. Shown in the middle are the tops that are identified from the axial slice. Shown on the right is the (optional) reference map. It can be an image file (e.g., .png, .fig, .tif, etc), or a .mat file containing either a matrix that reflects a grid or a set of coordinates. If they are coordinates, you can specify which 2 columns contain the x- and y-coordinates, respectively.'; ''; 'Find the axial (DV) slice where the tops are easily visible and, if necessary, best match your reference map/image. To focus the fiber top identification to a restricted area, zoom in such that only the fiber tops are visible. To do this, click "draw polygon" and then draw a polygon around the area, double-clicking to complete. You can repeat this iteratively. To reset to the original view, click "reset". '; ''; 'What is important is that all fiber tops are identified in the middle plot. Extras are ok; you will have the opportunity to remove fiber tops in the next step. Click Done to save slice#, zoom, and identified tops. The app will close when finished.'};
            app.DONEButton = uibutton(app.UIFigure, 'push');
            app.DONEButton.ButtonPushedFcn = createCallbackFcn(app, @DONEButtonPushed, true);
            app.DONEButton.Text = 'DONE';
            app.ManualContrast = uipanel(app.UIFigure);
            app.ManualContrast.Title = 'Manual Contrast';
            app.min_slider = uislider(app.ManualContrast);
            app.min_slider.Limits = [0 1];
            app.min_slider.MajorTicks = [];
            app.min_slider.ValueChangedFcn = createCallbackFcn(app, @min_sliderValueChanged, true);
            app.min_slider.MinorTicks = [];
            app.max_slider = uislider(app.ManualContrast);
            app.max_slider.Limits = [0 1];
            app.max_slider.MajorTicks = [];
            app.max_slider.ValueChangedFcn = createCallbackFcn(app, @max_sliderValueChanged, true);
            app.max_slider.MinorTicks = [];
            app.max_slider.Value = 1;
            app.minLabel = uilabel(app.ManualContrast);
            app.minLabel.Text = 'min';
            app.maxLabel = uilabel(app.ManualContrast);
            app.maxLabel.Text = 'max';
            app.hist_axes = uiaxes(app.ManualContrast);
            app.hist_axes.XTick = [];
            app.hist_axes.YTick = [];
            app.AutoContrast = uibuttongroup(app.UIFigure);
            app.AutoContrast.SelectionChangedFcn = createCallbackFcn(app, @AutoContrastSelectionChanged, true);
            app.AutoContrast.Title = 'Auto Contrast';
            app.raw_button = uitogglebutton(app.AutoContrast);
            app.raw_button.Text = 'raw';
            app.raw_button.Value = true;
            app.imadjust_button = uitogglebutton(app.AutoContrast);
            app.imadjust_button.Text = 'imadjust';
            app.histeq_button = uitogglebutton(app.AutoContrast);
            app.histeq_button.Text = 'histeq';
            app.adapthisteq_button = uitogglebutton(app.AutoContrast);
            app.adapthisteq_button.Text = 'adapthisteq';
            app.SlicePanel = uipanel(app.UIFigure);
            app.SlicePanel.Title = 'Slice #';
            app.sliceEditFieldLabel = uilabel(app.SlicePanel);
            app.sliceEditFieldLabel.Text = 'slice#';
            app.slice_num = uieditfield(app.SlicePanel, 'numeric');
            app.slice_num.ValueChangedFcn = createCallbackFcn(app, @slice_numValueChanged, true);
            app.PreviousButton = uibutton(app.SlicePanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.SlicePanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Panel_identified_tops = uipanel(app.UIFigure);
            app.topsidentifiedEditFieldLabel = uilabel(app.Panel_identified_tops);
            app.topsidentifiedEditFieldLabel.Text = '#tops identified';
            app.n_tops = uieditfield(app.Panel_identified_tops, 'numeric');
            app.n_tops.Editable = 'off';
            app.identifytopsButton = uibutton(app.Panel_identified_tops, 'push');
            app.identifytopsButton.ButtonPushedFcn = createCallbackFcn(app, @identifytopsButtonPushed, true);
            app.identifytopsButton.Text = 'identify tops';
            app.threshEditFieldLabel = uilabel(app.Panel_identified_tops);
            app.threshEditFieldLabel.Text = 'thresh';
            app.thresh_field = uieditfield(app.Panel_identified_tops, 'numeric');
            app.thresh_field.Value = 1.01;
            app.minareaEditFieldLabel = uilabel(app.Panel_identified_tops);
            app.minareaEditFieldLabel.Text = 'min area';
            app.minarea_field = uieditfield(app.Panel_identified_tops, 'numeric');
            app.minarea_field.Value = 15;
            app.axes_tops = uiaxes(app.Panel_identified_tops);
            title(app.axes_tops, 'identified tops')
            zlabel(app.axes_tops, 'Z')
            app.axes_tops.XTick = [];
            app.axes_tops.YTick = [];
            app.Panel_axial_slice = uipanel(app.UIFigure);
            app.drawpolygonButton = uibutton(app.Panel_axial_slice, 'push');
            app.drawpolygonButton.ButtonPushedFcn = createCallbackFcn(app, @drawpolygonButtonPushed, true);
            app.drawpolygonButton.Text = 'draw polygon';
            app.resetButton = uibutton(app.Panel_axial_slice, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.axes_axial = uiaxes(app.Panel_axial_slice);
            title(app.axes_axial, 'axial slice')
            zlabel(app.axes_axial, 'Z')
            app.axes_axial.XTick = [];
            app.axes_axial.YTick = [];
            app.Panel_ref = uipanel(app.UIFigure);
            app.locate_ref = uibutton(app.Panel_ref, 'push');
            app.locate_ref.ButtonPushedFcn = createCallbackFcn(app, @locate_refButtonPushed, true);
            app.locate_ref.Text = 'locate ref';
            app.path_ref = uieditfield(app.Panel_ref, 'text');
            app.reference_txt = uitextarea(app.Panel_ref);
            app.reference_txt.Editable = 'off';
            app.reference_txt.Value = {'Select an image (e.g., .fig, .png, etc). or .mat file. '};
            app.flipyButton = uibutton(app.Panel_ref, 'push');
            app.flipyButton.ButtonPushedFcn = createCallbackFcn(app, @flipyButtonPushed, true);
            app.flipyButton.Text = 'flip y';
            app.transposeButton = uibutton(app.Panel_ref, 'push');
            app.transposeButton.ButtonPushedFcn = createCallbackFcn(app, @transposeButtonPushed, true);
            app.transposeButton.Text = 'transpose';
            app.ButtonGroup = uibuttongroup(app.Panel_ref);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.matrixButton = uiradiobutton(app.ButtonGroup);
            app.matrixButton.Text = 'matrix';
            app.matrixButton.Value = true;
            app.coordsButton = uiradiobutton(app.ButtonGroup);
            app.coordsButton.Text = 'coords';
            app.coords_col1 = uieditfield(app.ButtonGroup, 'numeric');
            app.coords_col1.ValueChangedFcn = createCallbackFcn(app, @coords_col1ValueChanged, true);
            app.coords_col1.Enable = 'off';
            app.coords_col1.Value = 1;
            app.coords_col2 = uieditfield(app.ButtonGroup, 'numeric');
            app.coords_col2.ValueChangedFcn = createCallbackFcn(app, @coords_col2ValueChanged, true);
            app.coords_col2.Enable = 'off';
            app.coords_col2.Value = 2;
            app.axes_ref = uiaxes(app.Panel_ref);
            title(app.axes_ref, 'reference map (optional)')
            zlabel(app.axes_ref, 'Z')
            app.axes_ref.XTick = [];
            app.axes_ref.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fiber_top_ID(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end