classdef fiber_top_refine_and_label < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        refreshButton             matlab.ui.control.Button
        ButtonGroup               matlab.ui.container.ButtonGroup
        matrixButton              matlab.ui.control.RadioButton
        coordsButton              matlab.ui.control.RadioButton
        Label1xcol                matlab.ui.control.Label
        coords_col1               matlab.ui.control.NumericEditField
        Label2yrow                matlab.ui.control.Label
        coords_col2               matlab.ui.control.NumericEditField
        Label3ID                  matlab.ui.control.Label
        coords_col3               matlab.ui.control.NumericEditField
        assigngridCheckBox        matlab.ui.control.CheckBox
        transposeButton           matlab.ui.control.Button
        flipyButton               matlab.ui.control.Button
        reset_allButton           matlab.ui.control.Button
        topsTable                 matlab.ui.control.Table
        select_polygonButton      matlab.ui.control.Button
        select_lineButton         matlab.ui.control.Button
        select_singleButton       matlab.ui.control.Button
        deleteButton              matlab.ui.control.Button
        path_ref                  matlab.ui.control.EditField
        locate_ref                matlab.ui.control.Button
        selectionsEditField       matlab.ui.control.EditField
        selectionsEditFieldLabel  matlab.ui.control.Label
        DONEButton                matlab.ui.control.Button
        instructions              matlab.ui.control.TextArea
        axes_ref                  matlab.ui.control.UIAxes
        axes_tops                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        localize_main % the main localization app
        output = struct; % struct to store info   
    end
    
    methods (Access = private)
        
        function show_tops(app)
            pause(0.5) % to stop it from glitching
            
            % reset selection map
            app.output.tmp.tops_mask_disp = double(app.output.fiber_tops.tops_mask>0);
            % selected fiber (if applicable)
            if isfield(app.output,'tmp') && isfield(app.output.tmp,'current_fiber_idx') && ~isempty(app.output.tmp.current_fiber_idx)
                app.output.tmp.tops_mask_disp(ismember(app.output.fiber_tops.tops_mask, app.topsTable.Data(app.output.tmp.current_fiber_idx,1))) = 2;            
                app.selectionsEditField.Value = strjoin(cellstr(num2str(app.topsTable.Data(app.output.tmp.current_fiber_idx,1))),','); % update text field to reflect valid selections       
            end
            
            % show tops with selection
            imagesc(app.axes_tops,app.output.tmp.tops_mask_disp)
            set(app.axes_tops,'XTick',[],'YTick',[],...
                'XLim',[max([1 min(app.output.fiber_tops.axial_roi(:,1))-20]), ...
                min([size(app.output.fiber_tops.axial_slice,2) max(app.output.fiber_tops.axial_roi(:,1))+20])],...
                'YLim',[max([1 min(app.output.fiber_tops.axial_roi(:,2))-20]), ...
                min([size(app.output.fiber_tops.axial_slice,1) max(app.output.fiber_tops.axial_roi(:,2))+20])]);       
            caxis(app.axes_tops,[0 2])
            
            % update table selection
            white_bg = uistyle("BackgroundColor",[1 1 1]); % white background color
            addStyle(app.topsTable,white_bg,"row",1:size(app.topsTable.Data,1));
            
            % show selection if relevant
            if isfield(app.output,'tmp') && isfield(app.output.tmp,'current_fiber_idx') && ~isempty(app.output.tmp.current_fiber_idx)
                red_bg = uistyle("BackgroundColor",[1 0.6 0.6]); % red background color
                addStyle(app.topsTable,red_bg,"row",transpose(app.output.tmp.current_fiber_idx(:)));
                app.topsTable.Selection = [app.output.tmp.current_fiber_idx(:),ones(size(app.output.tmp.current_fiber_idx(:)))];
            end                                   
        end
        
        function update_table(app)
                     
            app.output.fiber_tops.tops_table = array2table(app.topsTable.Data,'VariableNames',app.topsTable.ColumnName);        
        end
        
        
        function show_ref(app)
            % first clear axes            
            cla(app.axes_ref)      
            hold(app.axes_ref,'on')
            % if it's an image, show it
            if strcmp(app.output.tmp.this_ref_type,'image')
                imshow(app.output.tmp.this_ref,'Parent',app.axes_ref)
            else % otherwise
                if app.coordsButton.Value == 1 % if coordinates, plot
                    plot(app.axes_ref,app.output.tmp.this_ref(:,app.coords_col1.Value),app.output.tmp.this_ref(:,app.coords_col2.Value),'ok')
                    
                    % if we are doing a grid and any are selected
                    if app.assigngridCheckBox.Value == 1 && isfield(app.output,'tmp') && isfield(app.output.tmp,'current_fiber_idx') && ~isempty(app.output.tmp.current_fiber_idx)                        
                        these_x = app.topsTable.Data(app.output.tmp.current_fiber_idx,2);
                        these_y = app.topsTable.Data(app.output.tmp.current_fiber_idx,3);
                        plot(app.axes_ref,these_x,these_y,'.r','MarkerSize',10)
                    end

                else % if a matrix, imagesc
                    % show matrix
                    mat_to_imagesc = double(app.output.tmp.this_ref>0);                    
                    imagesc(app.axes_ref,mat_to_imagesc);
                    % if we are doing a grid and any are selected
                    if app.assigngridCheckBox.Value == 1 && isfield(app.output,'tmp') && isfield(app.output.tmp,'current_fiber_idx') && ~isempty(app.output.tmp.current_fiber_idx)
                        these_x = app.topsTable.Data(app.output.tmp.current_fiber_idx,2);
                        these_y = app.topsTable.Data(app.output.tmp.current_fiber_idx,3);
                        plot(app.axes_ref,these_x,these_y,'.r','MarkerSize',10)
                    end                   
                end
            end            
            axis(app.axes_ref,'equal')            
        end
        
        function assign_grid(app)
            
            % let's first make a grid map
            if app.coordsButton.Value == 1
                app.output.tmp.this_ref(app.output.tmp.this_ref(:,app.coords_col1.Value)==0 |...
                    app.output.tmp.this_ref(:,app.coords_col2.Value)==0,:) = []; % get rid of zero'd out
                tmp_cols = app.output.tmp.this_ref(:,app.coords_col1.Value); % x = cols
                tmp_rows = app.output.tmp.this_ref(:,app.coords_col2.Value); % y = rows
                if (app.coords_col3.Value)>0
                    tmp_ID = app.output.tmp.this_ref(:,app.coords_col3.Value); % alt ID if applicable
                else
                    tmp_ID = ones(size(tmp_cols));
                end
                grid_mat_map_ID = zeros(max(tmp_rows),max(tmp_cols));
                grid_mat_map_ID(sub2ind(size(grid_mat_map_ID),tmp_rows,tmp_cols)) = tmp_ID;                 
            else
                grid_mat_map_ID = app.output.tmp.this_ref;
                
            end
            grid_mat_map = grid_mat_map_ID > 0;
            app.output.tmp.grid = grid_mat_map;
            app.output.tmp.grid_ID = grid_mat_map_ID;
            
            % auto-assign to columns/rows based on centroids            
            centroids = [app.output.fiber_tops.centers_ML,app.output.fiber_tops.centers_AP]; %                        
            assign = round((centroids-min(centroids))./range(centroids).*[size(app.output.tmp.grid,2)-1 size(app.output.tmp.grid,1)-1]+1); % col row
            assign = assign(ismember(1:size(assign,1),app.topsTable.Data(:,1)),:);
            app.topsTable.Data(:,3) = assign(:,2); % rows
            app.topsTable.Data(:,2) = assign(:,1); % cols
            
            % add in ID           
            app.topsTable.Data(:,4) = grid_mat_map_ID(sub2ind(size(grid_mat_map_ID),assign(:,2),assign(:,1)));            

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, localize_main_app, output)
            app.localize_main = localize_main_app; % the app info from the main localization
            app.output = output; % get the output from the main app
                        
            % if we haven't already, save the original mask
            if ~isfield(app.output.fiber_tops,'tops_mask_orig') % if we haven't already, save the original mask
                app.output.fiber_tops.tops_mask_orig = app.output.fiber_tops.tops_mask;
            end
            
            % mask to display selection
            app.output.tmp.tops_mask_disp = double(app.output.fiber_tops.tops_mask>0);
            
            % fill in table 
            if ~isfield(app.output.fiber_tops,'tops_table')
                tops_table = unique(app.output.fiber_tops.tops_mask(:));
                tops_table = tops_table(tops_table>0);
                tops_table = [tops_table nan(size(tops_table)) nan(size(tops_table)) nan(size(tops_table))];                
                app.topsTable.Data = tops_table; 
            else
                app.topsTable.Data = table2array(app.output.fiber_tops.tops_table);
                col_names = fieldnames(app.output.fiber_tops.tops_table);                
                app.topsTable.ColumnName = col_names(1:end-3);
            end            
            
            % table settings            
            app.topsTable.Multiselect = 'on';
            %app.topsTable.SelectionType = 'row';
            
            % now show the tops
            show_tops(app)
            
        end

        % Button pushed function: reset_allButton
        function reset_allButtonPushed(app, event)
            disp('reset')
            app.output.fiber_tops.tops_mask = app.output.fiber_tops.tops_mask_orig;            
            app.output.tmp.tops_mask_disp = double(app.output.fiber_tops.tops_mask>0);
            
            % reset table            
            tops_table = unique(app.output.fiber_tops.tops_mask(:));
            tops_table = tops_table(tops_table>0);
            tops_table = [tops_table nan(size(tops_table)) nan(size(tops_table)) nan(size(tops_table))];                
            app.topsTable.Data = tops_table;            
            app.topsTable.ColumnName = {'top_num','Label1','Label2','Label3'};
            
            % now show the tops
            update_table(app)     
            show_tops(app)
        end

        % Button pushed function: deleteButton
        function deleteButtonPushed(app, event)
            % fibers to delete
            fib_nums_to_delete = app.topsTable.Data(app.output.tmp.current_fiber_idx,1);
            % delete from mask
            app.output.fiber_tops.tops_mask(ismember(app.output.fiber_tops.tops_mask,fib_nums_to_delete)) = 0;
            % delete from table
            tmp_data = app.topsTable.Data;
            tmp_data(app.output.tmp.current_fiber_idx,:) = [];
            % update
            app.output.tmp.current_fiber_idx = [];
            app.selectionsEditField.Value = '';
            app.topsTable.Data = tmp_data;
            update_table(app)
            show_tops(app)
        end

        % Value changed function: selectionsEditField
        function selectionsEditFieldValueChanged(app, event)
            value = app.selectionsEditField.Value;
            top_nums = cellfun(@(x) str2double(x),(split(value,{' ',',',';'}))); % split by delimiters
            top_nums = top_nums(~isnan(top_nums) & ismember(top_nums,app.output.fiber_tops.tops_table(:,1))); % get rid of nans and invalid selections
            %app.selectionsEditField.Value = strjoin(cellstr(num2str(top_nums)),','); % update text field to reflect valid selections
            app.output.tmp.current_fiber_idx = find(ismember(app.output.fiber_tops.tops_table(:,1),top_nums)); % update idx
            
            % update display
            show_tops(app)                        
            
        end

        % Button pushed function: select_singleButton
        function select_singleButtonPushed(app, event)
                        
            % get user point
            upoint=drawpoint(app.axes_tops,'InteractionsAllowed','none');
            x = upoint.Position(1);
            y = upoint.Position(2); 

            % which fiber is closest
            fibdist = ((app.output.fiber_tops.centers_ML-x).^2+(app.output.fiber_tops.centers_AP-y).^2).^.5;
            [~,app.output.tmp.current_fiber_idx] = min(fibdist);

            % update display to reflect selected fiber                       
            show_tops(app)
            
        end

        % Button pushed function: select_lineButton
        function select_lineButtonPushed(app, event)
            row_col_line = drawline(app.axes_tops);
            x1 = row_col_line.Position(1,1);
            x2 = row_col_line.Position(2,1);
            y1 = row_col_line.Position(1,2);
            y2 = row_col_line.Position(2,2);
            
            % make a mask from the line;
            % Distance (in pixels) between the two endpoints
            nPoints = ceil(sqrt((x2 - x1).^2 + (y2 - y1).^2)) + 1;
            
            % Determine x and y locations along the line
            xvalues = round(linspace(x1, x2, nPoints));
            yvalues = round(linspace(y1, y2, nPoints));
            
            % Find the relevant values within the mask
            app.output.tmp.line_mask = zeros(size(app.output.fiber_tops.tops_mask));            
            app.output.tmp.line_mask(sub2ind(size(app.output.tmp.line_mask), yvalues, xvalues)) = 1;
            app.output.tmp.line_mask = app.output.tmp.line_mask.*app.output.fiber_tops.tops_mask;
            top_nums = unique(app.output.tmp.line_mask(:));
            top_nums = top_nums(top_nums>0);            
            app.output.tmp.current_fiber_idx = find(ismember(app.topsTable.Data(:,1),top_nums));
            
            % update
            show_tops(app)
            

        end

        % Button pushed function: select_polygonButton
        function select_polygonButtonPushed(app, event)
            this_poly = drawpolygon(app.axes_tops);
            polymask = roipoly(app.output.fiber_tops.tops_mask,...
                    this_poly.Position(:,1),this_poly.Position(:,2));
            polymask = app.output.fiber_tops.tops_mask(polymask);
            top_nums = unique(polymask(:));
            top_nums = top_nums(top_nums>0);   
            app.output.tmp.current_fiber_idx = find(ismember(app.topsTable.Data(:,1),top_nums));
            
            % update
            show_tops(app)
        end

        % Button pushed function: locate_ref
        function locate_refButtonPushed(app, event)
            [filename,filepath] = uigetfile('*.*');
            app.path_ref.Value = fullfile(filepath,filename);
            if endsWith(filename,'.mat')
                app.output.tmp.this_ref_type = 'mat';
                app.output.tmp.this_ref = load(fullfile(filepath,filename));                                
                ref_fields = fieldnames(app.output.tmp.this_ref);
                app.output.tmp.this_ref = app.output.tmp.this_ref.(ref_fields{1});
                app.ButtonGroup.Enable = 'on';
                % set these in case they got reset
                app.coords_col1.Value = 1;
                app.coords_col2.Value = 2;
            else
                app.output.tmp.this_ref_type = 'image';
                app.output.tmp.this_ref = imread(fullfile(filepath,filename));                
                app.ButtonGroup.Enable = 'off';
            end
            app.assigngridCheckBox.Value = 0;
            show_ref(app)
        end

        % Button pushed function: flipyButton
        function flipyButtonPushed(app, event)
            ydirs = {'normal','reverse'};
            set(app.axes_ref,'YDir',ydirs{~ismember(ydirs,get(app.axes_ref,'YDir'))})
        end

        % Button pushed function: transposeButton
        function transposeButtonPushed(app, event)
            if strcmp(app.output.tmp.this_ref_type,'image')
                % transpose by permuting the first 2 columns
                app.output.tmp.this_ref = permute(app.output.tmp.this_ref,[2 1 3]); 
            else
                if app.coordsButton.Value == 1
                    % transpose by switching these 2 columns
                    tmp = [app.coords_col1.Value app.coords_col2.Value];
                    app.coords_col1.Value = tmp(2);
                    app.coords_col2.Value = tmp(1);
                else
                    % transpose the matrix
                    app.output.tmp.this_ref = transpose(app.output.tmp.this_ref);
                end
            end
            show_ref(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            %selectedButton = app.ButtonGroup.SelectedObject;
            if app.coordsButton.Value == 1
                % turn on column selection
                app.coords_col1.Enable = 'on';
                app.coords_col2.Enable = 'on';
                app.coords_col3.Enable = 'on';
                % check that the column values are appropriate
                if app.coords_col1.Value > size(app.output.tmp.this_ref,2)
                    app.coords_col1.Value = size(app.output.tmp.this_ref,2);
                elseif app.coords_col1.Value < 1
                    app.coords_col1.Value = 1;
                end
                % check that the column values are appropriate
                if app.coords_col2.Value > size(app.output.tmp.this_ref,2)
                    app.coords_col2.Value = size(app.output.tmp.this_ref,2);
                elseif app.coords_col2.Value < 1
                    app.coords_col2.Value = 1;
                end
                % check that the column values are appropriate
                if app.coords_col3.Value > size(app.output.tmp.this_ref,2)
                    app.coords_col3.Value = 0;                
                end
            else
                % turn off column selection
                app.coords_col1.Enable = 'off';
                app.coords_col2.Enable = 'off';
                app.coords_col3.Enable = 'off';
            end
            show_ref(app)
        end

        % Callback function
        function coords_col1ValueChanged(app, event)
            % if we have a ref and are using coordinates
            if isfield(app.output.tmp, 'this_ref') && ...
                    app.coordsButton.Value == 1 
                % check that the values are appropriate
                if app.coords_col1.Value > size(app.output.tmp.this_ref,2)
                    app.coords_col1.Value = size(app.output.tmp.this_ref,2);
                elseif app.coords_col1.Value < 1
                    app.coords_col1.Value = 1;
                end
                show_ref(app)
            end              
        end

        % Callback function
        function coords_col2ValueChanged(app, event)
            % if we have a ref and are using coordinates
            if isfield(app.output.tmp, 'this_ref') && ...
                    app.coordsButton.Value == 1
                % check that the values are appropriate
                if app.coords_col2.Value > size(app.output.tmp.this_ref,2)
                    app.coords_col2.Value = size(app.output.tmp.this_ref,2);
                elseif app.coords_col2.Value < 1
                    app.coords_col2.Value = 1;
                end
                show_ref(app)
            end
            
        end

        % Value changed function: assigngridCheckBox
        function assigngridCheckBoxValueChanged(app, event)
            columnNames = app.topsTable.ColumnName;
            % if we change it to grid, make those Row and Col
            if app.assigngridCheckBox.Value == 1
                columnNames{2} = 'grid_col';
                columnNames{3} = 'grid_row';
                columnNames{4} = 'ID';
                app.topsTable.ColumnName = columnNames;
                app.output.fiber_tops.grid = 1;
                assign_grid(app)
            else % otherwise change them back                
                columnNames{2} = 'Label1';
                columnNames{3} = 'Label2';
                columnNames{4} = 'Label3';
                app.topsTable.ColumnName = columnNames;
                app.output.fiber_tops.grid = 0;
            end
                        
        end

        % Cell edit callback: topsTable
        function topsTableCellEdit(app, event)
            indices = event.Indices;            
            %newData = event.NewData;
            % update ID if we changed row or column and are doing grid
            % assignment
            if indices(2) == 2 || indices(2) == 3
                if app.assigngridCheckBox.Value == 1 && isfield(app.output,'tmp') && isfield(app.output.tmp,'grid_ID')      
                    disp('update2')
                    app.topsTable.Data(:,4) = app.output.tmp.grid_ID(sub2ind(size(app.output.tmp.grid_ID),app.topsTable.Data(:,3),app.topsTable.Data(:,2)));
                end   
            end
            update_table(app);
        end

        % Cell selection callback: topsTable
        function topsTableCellSelection(app, event)
            indices = event.Indices;             
            % only update selection if we select first column            
            top_indices = indices(:,2)==1;
            top_indices = indices(top_indices,1);
            if ~isempty(top_indices)
                app.output.tmp.current_fiber_idx = unique(top_indices);
                % update display
                show_tops(app)
                % update reference if we're doing a grid
                if app.assigngridCheckBox.Value == 1
                    show_ref(app)
                end
            end
            
            
        end

        % Display data changed function: topsTable
        function topsTableDisplayDataChanged(app, event)
            %newDisplayData = app.topsTable.DisplayData;
            update_table(app);
        end

        % Button pushed function: DONEButton
        function DONEButtonPushed(app, event)
            if isfield(app.output,'tmp')
                app.output = rmfield(app.output,'tmp');
            end            
            update_table(app)
            update_localization(app.localize_main,app.output,'fiber top refinement (labels optional) app finished')
            delete(app)
        end

        % Button pushed function: refreshButton
        function refreshButtonPushed(app, event)
            show_tops(app)
        end

        % Value changed function: coords_col1
        function coords_col1ValueChanged2(app, event)
            show_ref(app)            
        end

        % Value changed function: coords_col2
        function coords_col2ValueChanged2(app, event)
            show_ref(app)                        
        end

        % Value changed function: coords_col3
        function coords_col3ValueChanged(app, event)
            show_ref(app)                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.refreshButton = uibutton(app.UIFigure, 'push');
            app.refreshButton.ButtonPushedFcn = createCallbackFcn(app, @refreshButtonPushed, true);
            app.refreshButton.Text = 'refresh';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.matrixButton = uiradiobutton(app.ButtonGroup);
            app.matrixButton.Text = 'matrix';
            app.matrixButton.Value = true;
            app.coordsButton = uiradiobutton(app.ButtonGroup);
            app.coordsButton.Text = 'coords:';
            app.Label1xcol = uilabel(app.ButtonGroup);
            app.Label1xcol.Text = 'Label1/x/col';
            app.coords_col1 = uieditfield(app.ButtonGroup, 'numeric');
            app.coords_col1.ValueChangedFcn = createCallbackFcn(app, @coords_col1ValueChanged2, true);
            app.coords_col1.Enable = 'off';
            app.coords_col1.Value = 1;
            app.Label2yrow = uilabel(app.ButtonGroup);
            app.Label2yrow.Text = 'Label2/y/row';
            app.coords_col2 = uieditfield(app.ButtonGroup, 'numeric');
            app.coords_col2.ValueChangedFcn = createCallbackFcn(app, @coords_col2ValueChanged2, true);
            app.coords_col2.Enable = 'off';
            app.coords_col2.Value = 2;
            app.Label3ID = uilabel(app.ButtonGroup);
            app.Label3ID.Text = 'Label3/ID';
            app.coords_col3 = uieditfield(app.ButtonGroup, 'numeric');
            app.coords_col3.ValueChangedFcn = createCallbackFcn(app, @coords_col3ValueChanged, true);
            app.coords_col3.Enable = 'off';
            app.coords_col3.Value = 3;
            app.assigngridCheckBox = uicheckbox(app.ButtonGroup);
            app.assigngridCheckBox.ValueChangedFcn = createCallbackFcn(app, @assigngridCheckBoxValueChanged, true);
            app.assigngridCheckBox.Text = 'assign grid';
            app.transposeButton = uibutton(app.UIFigure, 'push');
            app.transposeButton.ButtonPushedFcn = createCallbackFcn(app, @transposeButtonPushed, true);
            app.transposeButton.Text = 'transpose';
            app.flipyButton = uibutton(app.UIFigure, 'push');
            app.flipyButton.ButtonPushedFcn = createCallbackFcn(app, @flipyButtonPushed, true);
            app.flipyButton.Text = 'flip y';
            app.reset_allButton = uibutton(app.UIFigure, 'push');
            app.reset_allButton.ButtonPushedFcn = createCallbackFcn(app, @reset_allButtonPushed, true);
            app.reset_allButton.Text = 'reset all';
            app.topsTable = uitable(app.UIFigure);
            app.topsTable.ColumnName = {'top_ID'; 'Label1'; 'Label2'; 'Label3'};
            app.topsTable.RowName = {};
            app.topsTable.ColumnSortable = [false true true true];
            app.topsTable.ColumnEditable = [false true true true];
            app.topsTable.CellEditCallback = createCallbackFcn(app, @topsTableCellEdit, true);
            app.topsTable.CellSelectionCallback = createCallbackFcn(app, @topsTableCellSelection, true);
            app.topsTable.DisplayDataChangedFcn = createCallbackFcn(app, @topsTableDisplayDataChanged, true);
            app.select_polygonButton = uibutton(app.UIFigure, 'push');
            app.select_polygonButton.ButtonPushedFcn = createCallbackFcn(app, @select_polygonButtonPushed, true);
            app.select_polygonButton.Text = 'select multiple: polygon';
            app.select_lineButton = uibutton(app.UIFigure, 'push');
            app.select_lineButton.ButtonPushedFcn = createCallbackFcn(app, @select_lineButtonPushed, true);
            app.select_lineButton.Text = 'select multiple: line';
            app.select_singleButton = uibutton(app.UIFigure, 'push');
            app.select_singleButton.ButtonPushedFcn = createCallbackFcn(app, @select_singleButtonPushed, true);
            app.select_singleButton.Text = 'select single top';
            app.deleteButton = uibutton(app.UIFigure, 'push');
            app.deleteButton.ButtonPushedFcn = createCallbackFcn(app, @deleteButtonPushed, true);
            app.deleteButton.Text = 'delete';
            app.path_ref = uieditfield(app.UIFigure, 'text');
            app.locate_ref = uibutton(app.UIFigure, 'push');
            app.locate_ref.ButtonPushedFcn = createCallbackFcn(app, @locate_refButtonPushed, true);
            app.locate_ref.Text = 'locate ref';
            app.selectionsEditField = uieditfield(app.UIFigure, 'text');
            app.selectionsEditField.ValueChangedFcn = createCallbackFcn(app, @selectionsEditFieldValueChanged, true);
            app.selectionsEditFieldLabel = uilabel(app.UIFigure);
            app.selectionsEditFieldLabel.Text = 'selection #s';
            app.DONEButton = uibutton(app.UIFigure, 'push');
            app.DONEButton.ButtonPushedFcn = createCallbackFcn(app, @DONEButtonPushed, true);
            app.DONEButton.Text = 'DONE';
            app.instructions = uitextarea(app.UIFigure);
            app.instructions.Editable = 'off';
            app.instructions.Value = {'The primary goal in this step is to delete any identified fiber tops as necessary, and if desired, to assign labels to the fiber tops. You may assign up to 3 numeric labels for each fiber top (e.g., for a grid arrangement, you might want to assign columns and rows). Shown in the table is the top_num identifier, and the labels, which are manually editable. The top_num number labels are arbitrarily assigned, and you won''t be able to change them, though you can create a new identifier as one of the other labels. The columns are sortable.'; ''; 'Shown on the left are the tops identified in the last step. To select fiber tops, you can click one of the selection options, or you can select the fibers of interest in the top_num column of the table. The selected tops will appear in the selection #s field (which you can also edit to select fibers). Selected fibers will be highlighted in the table and on the plot.'; ''; 'As in the last step, the right axes will show the (optional) reference map. As in the previous step, it can be an image file, a matrix (e.g., for a grid-like arrangement), or an array of coordinates. You can specify which columns contain which coordinates, and alternate ID (set to 0 if you don''t have an alternate ID). If you have a grid arrangement (matrix, or as row and column coordinates), you can check the "assign grid" box to generate an automatic assigment, which you can then manually edit as necessary.'};
            app.axes_ref = uiaxes(app.UIFigure);
            title(app.axes_ref, 'reference map (optional)')
            zlabel(app.axes_ref, 'Z')
            app.axes_ref.XTick = [];
            app.axes_ref.YTick = [];
            app.axes_tops = uiaxes(app.UIFigure);
            title(app.axes_tops, 'tops')
            zlabel(app.axes_tops, 'Z')
            app.axes_tops.XTick = [];
            app.axes_tops.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fiber_top_refine_and_label(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end