classdef orient_CT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        locate_ct            matlab.ui.control.Button
        path_ct              matlab.ui.control.EditField
        okgo                 matlab.ui.control.Button
        status_text          matlab.ui.control.TextArea
        DisplayColor         matlab.ui.container.ButtonGroup
        gray_button          matlab.ui.control.ToggleButton
        parula_button        matlab.ui.control.ToggleButton
        AutoContrast         matlab.ui.container.ButtonGroup
        raw_button           matlab.ui.control.ToggleButton
        imadjust_button      matlab.ui.control.ToggleButton
        histeq_button        matlab.ui.control.ToggleButton
        adapthisteq_button   matlab.ui.control.ToggleButton
        ManualContrast       matlab.ui.container.Panel
        min_slider           matlab.ui.control.Slider
        max_slider           matlab.ui.control.Slider
        minLabel             matlab.ui.control.Label
        maxLabel             matlab.ui.control.Label
        hist_axes            matlab.ui.control.UIAxes
        Reset                matlab.ui.control.Button
        DisplayOptionsLabel  matlab.ui.control.Label
        Next                 matlab.ui.control.Button
        main_axes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        output = struct;
    end
    
    methods (Access = private)
        
        function update_status(app,update_str)
            app.status_text.Value = update_str;
            pause(0.5)
        end
        
        function hist_contrast(app)
            if ~isempty(app.output.snapshot)
                axes(app.hist_axes)
                cla(app.hist_axes)
                hold(app.hist_axes,'on')
%                 minmax = double(max(app.output.snapshot(:)))*...
%                     [app.min_slider.Value app.max_slider.Value];
                minmax = (2^16)*[app.min_slider.Value app.max_slider.Value]; % int16
                hg = histogram(app.hist_axes,app.output.snapshot(:));
                xline(app.hist_axes,minmax(1),'-r')
                xline(app.hist_axes,minmax(2),'-r')
                %set(app.hist_axes,'XTick',[],'YTick',[],'XLim',[0 max(app.output.snapshot(:))],'YLim',[0 max(hg.Values(2:end))])  
                set(app.hist_axes,'XTick',[],'YTick',[],'XLim',[0 2^16],'YLim',[0 max(hg.Values(2:end))])  
                main_display(app);
            end
        end
        
        function main_display(app)            
            axes(app.main_axes)
            cla(app.main_axes)
            % first threshold according to sliders
            app.output.snapshotThresh = imadjust(uint16(app.output.snapshot),...
                [app.min_slider.Value app.max_slider.Value]);
            % now let's display according to auto contrast
            if app.imadjust_button.Value == 1
                imagesc(app.main_axes,imadjust(app.output.snapshotThresh))
            elseif app.histeq_button.Value == 1
                imagesc(app.main_axes,histeq(app.output.snapshotThresh))
            elseif app.adapthisteq_button.Value == 1
                imagesc(app.main_axes,adapthisteq(app.output.snapshotThresh))   
            else
                imagesc(app.main_axes,app.output.snapshotThresh)
            end             
            % colormap
            if app.gray_button.Value == 1
                colormap(app.main_axes,'gray')
            elseif app.parula_button.Value == 1
                colormap(app.main_axes,'parula')
            end
            % axes settings
            set(app.main_axes,'YLim',[0 size(app.output.snapshotThresh,1)]+1,...
                'XLim',[0 size(app.output.snapshotThresh,2)+1])
            axis(app.main_axes,'equal')
            hold(app.main_axes,'on')
            % plot current points
            if ~isempty(app.output.current_pts)                              
                plot(app.main_axes,app.output.current_pts(:,1),app.output.current_pts(:,2),'ok','MarkerFaceColor','r')                
            end
            % if we need more points, add another one (could loop but this
            % seems a little less clunky somehow)
            if app.output.npts > 0 && size(app.output.current_pts,1) < app.output.npts
                try
                    h = drawpoint(app.main_axes,'Color','r');
                    app.output.current_pts = [app.output.current_pts; h.Position];
                    if app.output.current_phase == 3 && size(app.output.current_pts,1)>1
                        plot(app.main_axes,app.output.current_pts(:,1),app.output.current_pts(:,2),'-r')
                    end 
                catch 
                end
            end           
            if size(app.output.current_pts,1) < app.output.npts % run again
                main_display(app)
            end
        end
        
        function update_app_phase(app)
            switch app.output.current_phase
                case 1 % if we just did axial selection
                    ok_axial = confirm_axial_slice(app);
                    if ok_axial == 0
                        update_status(app,'Please try again.')
                        choose_axial_slice(app);
                    else % let's move onto midsagittal point selection
                        app.output.current_phase = 2;
                        app.output.midsag_slice_step = 20; % we'll take every 20th slice
                        app.output.current_slice = app.output.midsag_slice_step;
                        app.output.midsag_pts = [];
                        select_midsag_pts(app);
                    end
                case 2 % we're selecting midsagittal points
                    
                    % add current pts to our list of midsag pts
                    if ~isempty(app.output.current_pts)
                        app.output.midsag_pts = [app.output.midsag_pts;...
                            app.output.current_pts,...
                            ones(size(app.output.current_pts(:,1)))*app.output.current_slice];        
                    end
                        
                    % if we're not yet at the end, keep going
                    if app.output.current_slice + app.output.midsag_slice_step <= size(app.output.ct,3)
                        app.output.current_slice = app.output.current_slice + app.output.midsag_slice_step;                       
                        select_midsag_pts(app);
                    else
                        % warn use if there aren't enough points
                        if size(app.output.midsag_pts,1) < 3
                            update_status(app,'Definitely not enough points selected. Recommendation: close this app and start over.')
                        end
                        app.Next.Enable = 'off';
                        ok_midsag = align_midsag_pts(app);                        
                        app.Next.Enable = 'on';
                        if ok_midsag == 0
                            update_status(app,'Please try this step again.')
                            app.Next.Enable = 'on';
                            app.output.current_slice = app.output.midsag_slice_step;
                            app.output.midsag_pts = [];
                            select_midsag_pts(app);
                        else % let's move onto anterior/posterior orientation
                            app.output.current_phase = 3;                            
                            select_pitch(app);
                        end
                        
                    end
                    
                case 3 % we're orienting the pitch 
                    app.Next.Enable = 'off';
                    ok_pitch = align_pitch(app);
                    app.Next.Enable = 'on';
                    if ok_pitch == 0
                        update_status(app,'Please try again.')
                        select_pitch(app);
                    else
                        app.output.current_phase = 4;
                        choose_anterior(app);
                    end
                    
                case 4 % we're orienting the scan (anterior/posterior)
                    app.Next.Enable = 'off';
                    ok_anterior = align_anterior(app);
                    app.Next.Enable = 'on';
                    if ok_anterior == 0
                        update_status(app,'Please try again.')
                        choose_anterior(app);
                    else % let's move onto dorsal/ventral orientation
                        app.output.current_phase = 5;
                        choose_dorsal(app);
                    end
                    
                case 5 % we're orienting the scan (dorsal/ventral)
                    app.Next.Enable = 'off';
                    ok_dorsal = align_dorsal(app);                    
                    if ok_dorsal == 0
                        update_status(app,'Please try again.')
                        choose_dorsal(app);
                    else
                        app.output.current_phase = 6;
                        save_ct(app)
                    end
                
            end
            
        end
        
        function choose_axial_slice(app)
            update_status(app,['You will eventually be choosing points along the midsagittal ',...
                'plane, so first let''s figure out the best orientation for doing that. ',...
                'In each row you will see a few slices from one orientation. ', ...
                'Click the orientation (row) in which you will best be able to visualize ', ...
                'the midsagittal plane. The axial (horizontal) view tends to work well, though ',...
                'the coronal view will also work. Use the display options to adjust the display ',...
                'as needed. Click Reset if you need to change your mind. Click Next once you are ',...
                'satisfied with your choice.',...
                ' (Tip: if for some reason the display fails to update color/contrast options, ',...
                'changing one of the sliders in the Manual Contrast seems to force the update.)'])
            largest_dim = max(size(app.output.ct));
            big_mat = zeros(largest_dim*3,largest_dim*3);
            allct.row1 = permute(app.output.ct(round([.25 .5 .75]*size(app.output.ct,1)),:,:),[2 3 1]);
            allct.row2 = permute(app.output.ct(:,round([.25 .5 .75]*size(app.output.ct,2)),:),[1 3 2]);
            allct.row3 = app.output.ct(:,:,round([.25 .5 .75]*size(app.output.ct,3)));
            for i = 1:3
                for j = 1:3
                    this_slice = allct.(['row' num2str(i)])(:,:,j);
                    i1 = (i-1)*largest_dim;
                    j1 = (j-1)*largest_dim;
                    big_mat(i1+(1:size(this_slice,1)),j1+(1:size(this_slice,2))) = this_slice;
                end
            end
            app.output.snapshot = big_mat;
            app.output.npts = 1;
            app.output.current_pts = [];
            app.min_slider.Value = 0;
            app.max_slider.Value = 1;
            hist_contrast(app);
        end
        
        
        function ok = confirm_axial_slice(app)
            ok = 0;
            dims = [1 2 3];
            if size(app.output.current_pts,1) == 1 
                % the row we clicked
                axial_dim = ceil(app.output.current_pts(1,2)/(size(app.output.snapshot,1)/3));
                dims(axial_dim) = [];
                dims = [dims axial_dim]; 
                % permute ct so the "axial" (or chosen) dimension is 3rd
                app.output.ct = permute(app.output.ct,dims);
                ok = 1;
            end
        end
        
        function select_midsag_pts(app)
            
            % update status
            update_status(app,['Let''s establish that midsagittal plane. For each ',...
                'slice, click 0-5 points along the midsagittal line. ',...
                'You can adjust the display using the the Display Options. The more points, the better. The more ',...
                'spread out, the better. If you need, you can reset the slice by clicking ',...
                'Reset. Once you have clicked your 0-5 points (or if you ',...
                'cannot find the midsagittal line on this slice), click Next to continue ',...
                'onto the next slice. We''ll do this for every 20th slice. ',...
                'Note: In some brains, the olfactory bulbs might ',...
                'not be very reliable landmarks.',...
                ' (Tip: if for some reason the display fails to update color/contrast options, ',...
                'changing one of the sliders in the Manual Contrast seems to force the update.)'])
            
            % get slice
            app.output.snapshot = app.output.ct(:,:,app.output.current_slice);            
            
            % reset points
            app.output.npts = 5;
            app.output.current_pts = [];
            
            % now reset display options 
%             app.raw_button.Value = 1;
            app.min_slider.Value = 0;
            app.max_slider.Value = 1;
            hist_contrast(app)

 
        end


        function ok = align_midsag_pts(app)
            ok = 1;
            update_status(app,'Calculating midsagittal plane...')
            % let's make a black box with the midsag pts marked in white
            % this will help us figure out the midsag plane later
            try
                block = zeros(size(app.output.ct));                   
                lin_idx = sub2ind(size(block),...
                    round(app.output.midsag_pts(:,2)),... % y
                    round(app.output.midsag_pts(:,1)),... % x
                    round(app.output.midsag_pts(:,3))); % z
                block(lin_idx) = 1;           
                
                % figure out the transformation
                v = table(app.output.midsag_pts(:,1),...
                    app.output.midsag_pts(:,2),...
                    app.output.midsag_pts(:,3),'VariableNames',{'x','y','z'});
                mdlspec = 'y ~ x + z';
                mdl = fitglm(v,mdlspec);
                coef = [mdl.Coefficients.Estimate];
                nrm = [coef(2) -1 coef(3)]; 
                ref = [1 0 0];
                % the rotation vector needed to get there
                r = vrrotvec(ref,nrm); 
                % turn it into a transform
                tform = affine3d;
                tform.T = rotm2tform(vrrotvec2mat(r));
                
                % apply the transform
                data = imwarp(app.output.ct,tform);
                block = imwarp(block,tform);
                
                % do some trimming
                colsum = sum(data,[1 3]);
                rowsum = sum(data,[2 3]);
                block = block(rowsum>0,colsum>0,:);
                data = data(rowsum>0,colsum>0,:);
                
                % now find the midsag plane
                xsums = sum(block,[1 3]);
                [~,midsag_plane]=max(xsums);
                % now pad the image with zeros to make it symmetrical
                hemi_width = max(abs([midsag_plane size(block,2)-midsag_plane]));
                new_width = 2*hemi_width-1;
                pad_width = new_width-size(block,2);
                padding = zeros(size(block,1),pad_width,size(block,3));
                if midsag_plane < hemi_width % pad left
                    data = cat(2,padding,data);
                else % pad right
                    data = cat(2,data,padding);
                end
                
                % now overwite ct with the new one
                app.output.ct = data;
                clear data;
            catch exception
                disp(exception)
                ok = 0;
            end
        end
        

        function select_pitch(app)
            update_status(app,['Now we need to adjust the pitch angle to make ',...
                'it easier to find landmarks later. On the midsagittal slice shown, click ',...
                'endpoints to draw a line along the anterior-posterior long axis, like the ',...
                'diagram shown. It doesn''t need to be perfect.',...
                ' (Tip: if for some reason the display fails to update color/contrast options, ',...
                'changing one of the sliders in the Manual Contrast seems to force the update.)'])
            this_dir = fileparts(mfilename('fullpath'));
            pitch_guide = imread(fullfile(this_dir,'midsag_axis_diagram.png'));
            app.output.tmp_fig = figure;
            tmp_ax = axes(app.output.tmp_fig);
            imshow(pitch_guide,'Parent',tmp_ax)
            app.output.npts = 2;
            app.output.current_pts = [];
            app.output.snapshot = squeeze(app.output.ct(:,round(size(app.output.ct,2)/2),:));
            app.min_slider.Value = 0;
            app.max_slider.Value = 1;
            hist_contrast(app)
        end
        
        function ok = align_pitch(app)
            ok = 0;
            if size(app.output.current_pts,1) == 2
                close(app.output.tmp_fig)
                ang_data = atand(diff(app.output.current_pts(:,2))/diff(app.output.current_pts(:,1)));
                ang_atlas = 0;
                ang_diff = ang_atlas-ang_data;
                data = permute(app.output.ct,[1 3 2]);
                data = imrotate(data,ang_diff);
                data = permute(data,[1 3 2]);
                % trim AP and DV edges (leave ML alone for symmetry)
                rowsum = sum(data,[2 3]);
                zsum = sum(data,[1 2]);
                data = data(rowsum>0,:,zsum>0);
                app.output.ct = data;
                clear data;
                ok = 1;
            end
        end
        
        
        function choose_anterior(app)
            update_status(app,['Now we need to sort out anterior/posterior orientation, ',...
                'i.e., front/back. Click the anterior side (front). Click Next when finished.',...
                ' (Tip: if for some reason the display fails to update color/contrast options, ',...
                'changing one of the sliders in the Manual Contrast seems to force the update.)'])
            % axial slice           
            tmp = round(size(app.output.ct,1)/2);
            
            app.output.snapshot = squeeze(app.output.ct(tmp,:,:));
            app.output.npts = 1;
            app.output.current_pts = [];
            app.min_slider.Value = 0;
            app.max_slider.Value = 1;
            hist_contrast(app);
        end
        
        function ok = align_anterior(app)
            ok = 0;
            update_status(app,'Establishing anterior/posterior orientation...');
            % first check if we have a point
            if size(app.output.current_pts,1) == 1
                % if x is to the right of halfway, we need to rotate it
                if app.output.current_pts(1,1) > size(app.output.snapshot,2)/2 
                    tform = affine3d;
                    % rotate it around the y-axis (xz plane)
                    tform.T = [cosd(180) 0 sind(180) 0;...
                        0 1 0 0;...
                        -sind(180) 0 cosd(180) 0;...
                        0 0 0 1];
                    app.output.ct = imwarp(app.output.ct,tform); 
                end
                ok = 1;
            end
        end
        
        
        function choose_dorsal(app)
            update_status(app,['Now we need to sort out dorsal/ventral orientation, ',...
                'i.e., top/bottom. Click the dorsal side (top). Click Next when finished.',...
                ' (Tip: if for some reason the display fails to update color/contrast options, ',...
                'changing one of the sliders in the Manual Contrast seems to force the update.)'])
            % coronal slice
            app.output.snapshot = squeeze(app.output.ct(:,:,round(size(app.output.ct,3)/2)));
            app.output.npts = 1;
            app.output.current_pts = [];
            app.min_slider.Value = 0;
            app.max_slider.Value = 1;
            hist_contrast(app);
        end
        
        function ok = align_dorsal(app)
            ok = 0;
            update_status(app,'Establishing dorsal/ventral orientation...');
            % first check if we have a point
            if size(app.output.current_pts,1) == 1 
                % if y is more than halfway, we need to rotate it
                if app.output.current_pts(1,2) > size(app.output.snapshot,1)/2 
                    tform = affine3d;
                    % rotate it around the z-axis (xy plane)
                    tform.T = [cosd(180) -sind(180) 0 0;...
                        sind(180) cosd(180) 0 0;...
                        0 0 1 0;...
                        0 0 0 1];
                    app.output.ct = imwarp(app.output.ct,tform);  
                end
                ok = 1;
            end
        end
        
              
        function save_ct(app)
            outfilepath = [app.path_ct.Value(1:end-4) '_coronal.tif'];
            update_status(app,['Saving out oriented .tif file: ',...
                outfilepath '. This app will close when finished.']);
            fTIF = Fast_BigTiff_Write(outfilepath,0,Tiff.Compression.LZW);    
            for i = 1:size(app.output.ct,3)
                fTIF.WriteIMG(app.output.ct(:,:,i));
            end
            fTIF.close;          
            delete(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on'; 
            pause(1)
        end

        % Button pushed function: locate_ct
        function locate_ctButtonPushed(app, event)
            [filename,filepath] = uigetfile('*.tif');
            app.path_ct.Value = fullfile(filepath,filename);
            app.okgo.Enable = 1;
        end

        % Button pushed function: okgo
        function okgoButtonPushed(app, event)
            % turn off tiff library warnings
            warning('off','imageio:tiffmexutils:libtiffWarning')
            warning('off','imageio:tiffmexutils:libtiffErrorAsWarning')
            warning('off','imageio:tifftagsread:expectedTagDataFormatMultiple')
            % update status
            update_status(app,'loading data...')
            % load tiff
            app.output.ct = load_tiffs_fast(app.path_ct.Value);
            app.output.ct = imadjustn(app.output.ct,double([min(app.output.ct(:)); max(app.output.ct(:))]/2^16),[0; 1]); % map to int16
            app.output.ct = imadjustn(app.output.ct);
            app.output.snapshot = [];
            app.output.snapshotThresh = [];
            app.output.npts = 0;
            app.output.current_pts = [];
            app.output.current_phase = 1; % use this to track which phase we're in
            % disable some things
            app.locate_ct.Enable = 'off';
            app.path_ct.Enable = 'off';
            app.okgo.Enable = 'off';            
            % enable things
            app.DisplayColor.Enable = 'on';
            app.AutoContrast.Enable = 'on';
            app.ManualContrast.Enable = 'on';
            app.Reset.Enable = 'on';
            app.Next.Enable = 'on';
            % now go to axial slice selection
            choose_axial_slice(app)
        end

        % Value changed function: path_ct
        function path_ctValueChanged(app, event)
            value = app.path_ct.Value;
            if exist(value,'file')
                app.okgo.Enable = 1;
            end
        end

        % Selection changed function: AutoContrast
        function AutoContrastSelectionChanged(app, event)
%             selectedButton = app.AutoContrast.SelectedObject;            
%             hist_contrast(app)
            main_display(app)
        end

        % Value changed function: max_slider
        function max_sliderValueChanged(app, event)
            % don't let it be less than the min
            if app.max_slider.Value <= app.min_slider.Value
                app.max_slider.Value = app.min_slider.Value + .01;
            end
            hist_contrast(app)
            
        end

        % Value changed function: min_slider
        function min_sliderValueChanged(app, event)
            % don't let it be greater than the max
            if app.min_slider.Value >= app.max_slider.Value
                app.min_slider.Value = app.max_slider.Value - .01;
            end
            hist_contrast(app)
            
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            app.output.current_pts = [];
            main_display(app);
        end

        % Button pushed function: Next
        function NextButtonPushed(app, event)
            update_app_phase(app)            
        end

        % Selection changed function: DisplayColor
        function DisplayColorSelectionChanged(app, event)
%             selectedButton = app.DisplayColor.SelectedObject;
            main_display(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.locate_ct = uibutton(app.UIFigure, 'push');
            app.locate_ct.ButtonPushedFcn = createCallbackFcn(app, @locate_ctButtonPushed, true);
            app.locate_ct.Text = 'Locate CT (.tif)';
            app.path_ct = uieditfield(app.UIFigure, 'text');
            app.path_ct.ValueChangedFcn = createCallbackFcn(app, @path_ctValueChanged, true);
            app.path_ct.Value = '/path/to/CT.tif';
            app.okgo = uibutton(app.UIFigure, 'push');
            app.okgo.ButtonPushedFcn = createCallbackFcn(app, @okgoButtonPushed, true);
            app.okgo.Enable = 'off';
            app.okgo.Text = 'OK GO';
            app.status_text = uitextarea(app.UIFigure);
            app.status_text.Editable = 'off';
            app.DisplayColor = uibuttongroup(app.UIFigure);
            app.DisplayColor.SelectionChangedFcn = createCallbackFcn(app, @DisplayColorSelectionChanged, true);
            app.DisplayColor.Enable = 'off';
            app.DisplayColor.Title = 'Color';
            app.gray_button = uitogglebutton(app.DisplayColor);
            app.gray_button.Text = 'Gray';
            app.gray_button.Value = true;
            app.parula_button = uitogglebutton(app.DisplayColor);
            app.parula_button.Text = 'Parula';
            app.AutoContrast = uibuttongroup(app.UIFigure);
            app.AutoContrast.SelectionChangedFcn = createCallbackFcn(app, @AutoContrastSelectionChanged, true);
            app.AutoContrast.Enable = 'off';
            app.AutoContrast.Title = 'Auto Contrast';
            app.raw_button = uitogglebutton(app.AutoContrast);
            app.raw_button.Text = 'raw';
            app.raw_button.Value = true;
            app.imadjust_button = uitogglebutton(app.AutoContrast);
            app.imadjust_button.Text = 'imadjust';
            app.histeq_button = uitogglebutton(app.AutoContrast);
            app.histeq_button.Text = 'histeq';
            app.adapthisteq_button = uitogglebutton(app.AutoContrast);
            app.adapthisteq_button.Text = 'adapthisteq';
            app.ManualContrast = uipanel(app.UIFigure);
            app.ManualContrast.Enable = 'off';
            app.ManualContrast.Title = 'Manual Contrast';
            app.min_slider = uislider(app.ManualContrast);
            app.min_slider.Limits = [0 1];
            app.min_slider.MajorTicks = [];
            app.min_slider.ValueChangedFcn = createCallbackFcn(app, @min_sliderValueChanged, true);
            app.min_slider.MinorTicks = [];
            app.max_slider = uislider(app.ManualContrast);
            app.max_slider.Limits = [0 1];
            app.max_slider.MajorTicks = [];
            app.max_slider.ValueChangedFcn = createCallbackFcn(app, @max_sliderValueChanged, true);
            app.max_slider.MinorTicks = [];
            app.max_slider.Value = 1;
            app.minLabel = uilabel(app.ManualContrast);
            app.minLabel.Text = 'min';
            app.maxLabel = uilabel(app.ManualContrast);
            app.maxLabel.Text = 'max';
            app.hist_axes = uiaxes(app.ManualContrast);
            app.hist_axes.XTick = [];
            app.hist_axes.YTick = [];
            app.Reset = uibutton(app.UIFigure, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Enable = 'off';
            app.Reset.Text = 'Reset';
            app.DisplayOptionsLabel = uilabel(app.UIFigure);
            app.DisplayOptionsLabel.Text = 'Display Options';
            app.Next = uibutton(app.UIFigure, 'push');
            app.Next.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.Next.Enable = 'off';
            app.Next.Text = 'Next';
            app.main_axes = uiaxes(app.UIFigure);
            app.main_axes.XTick = [];
            app.main_axes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = orient_CT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end