classdef verify_atlas_labels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FiberListBoxLabel       matlab.ui.control.Label
        FiberListBox            matlab.ui.control.ListBox
        load_fibers_button      matlab.ui.control.Button
        fiber_counts_panel      matlab.ui.container.Panel
        rejectLabel             matlab.ui.control.Label
        count_reject            matlab.ui.control.NumericEditField
        unsureLabel             matlab.ui.control.Label
        count_unsure            matlab.ui.control.NumericEditField
        keepLabel               matlab.ui.control.Label
        count_keep              matlab.ui.control.NumericEditField
        current_location_panel  matlab.ui.container.Panel
        idx_orig                matlab.ui.container.Panel
        APindexLabel_3          matlab.ui.control.Label
        AP_orig                 matlab.ui.control.NumericEditField
        MLindexLabel_3          matlab.ui.control.Label
        ML_orig                 matlab.ui.control.NumericEditField
        DVindexLabel_3          matlab.ui.control.Label
        DV_orig                 matlab.ui.control.NumericEditField
        keep_reject_buttons     matlab.ui.container.ButtonGroup
        keepButton              matlab.ui.control.RadioButton
        rejectButton            matlab.ui.control.RadioButton
        unsureButton            matlab.ui.control.RadioButton
        Reset                   matlab.ui.control.Button
        fp_label_orig           matlab.ui.control.TextArea
        ccf_label_orig          matlab.ui.control.TextArea
        new_location_panel      matlab.ui.container.Panel
        fp_label_new            matlab.ui.control.TextArea
        save_new_label          matlab.ui.control.Button
        idx_new                 matlab.ui.container.Panel
        APindexLabel_4          matlab.ui.control.Label
        AP_new                  matlab.ui.control.NumericEditField
        MLindexLabel_4          matlab.ui.control.Label
        ML_new                  matlab.ui.control.NumericEditField
        DVindexLabel_4          matlab.ui.control.Label
        DV_new                  matlab.ui.control.NumericEditField
        ccf_label_new           matlab.ui.control.TextArea
        save_idx_new            matlab.ui.control.CheckBox
        atlas_View              matlab.ui.container.ButtonGroup
        view_structural         matlab.ui.control.RadioButton
        view_labels             matlab.ui.control.RadioButton
        SaveButton              matlab.ui.control.Button
        locate_atlas            matlab.ui.control.Button
        path_atlas              matlab.ui.control.EditField
        TextArea                matlab.ui.control.TextArea
        coronalPanel            matlab.ui.container.Panel
        click_coronal           matlab.ui.control.Button
        APLabel                 matlab.ui.control.Label
        AP_slice                matlab.ui.control.NumericEditField
        axes_coronalCT          matlab.ui.control.UIAxes
        axes_coronalAtlas       matlab.ui.control.UIAxes
        axialPanel              matlab.ui.container.Panel
        click_axial             matlab.ui.control.Button
        DVLabel                 matlab.ui.control.Label
        DV_slice                matlab.ui.control.NumericEditField
        axes_axialAtlas         matlab.ui.control.UIAxes
        axes_axialCT            matlab.ui.control.UIAxes
        sagittalPanel           matlab.ui.container.Panel
        click_sagittal          matlab.ui.control.Button
        MLLabel                 matlab.ui.control.Label
        ML_slice                matlab.ui.control.NumericEditField
        axes_sagittalCT         matlab.ui.control.UIAxes
        axes_sagittalAtlas      matlab.ui.control.UIAxes
        path_fiber              matlab.ui.control.EditField
        ok                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        output = struct; % struct to store info
    end
    
    methods (Access = private)
        
        function enable_components(app)
            app.SaveButton.Enable = 'on';   
            app.FiberListBox.Enable = 'on';
            app.click_sagittal.Enable = 'on';
            app.click_axial.Enable = 'on';
            app.click_coronal.Enable = 'on';
            app.coronalPanel.Enable = 'on';
            app.axialPanel.Enable = 'on';
            app.sagittalPanel.Enable = 'on';
            app.atlas_View.Enable = 'on';
            app.current_location_panel.Enable = 'on';
            app.new_location_panel.Enable = 'on';
            app.fiber_counts_panel.Enable = 'on';
        end
        
        function disable_components(app)
            app.SaveButton.Enable = 'off';   
            app.FiberListBox.Enable = 'off';
            app.click_sagittal.Enable = 'off';
            app.click_axial.Enable = 'off';
            app.click_coronal.Enable = 'off';
            app.coronalPanel.Enable = 'off';
            app.axialPanel.Enable = 'off';
            app.sagittalPanel.Enable = 'off';
            app.atlas_View.Enable = 'off';
            app.current_location_panel.Enable = 'off';
            app.new_location_panel.Enable = 'off';
            app.fiber_counts_panel.Enable = 'off';
        end
        
        function load_things(app)          
            disp('loading... ')
            % back up table if there isn't already a backup
            if ~isfield(app.output,'fiber_table_orig')
                app.output.fiber_table_orig = app.output.fiber_table;
            end
            % get good rois and populate the listbox
            app.output.tmp.good_rois = find(app.output.fiber_table.top_DV_idx > 0 & ...
                app.output.fiber_table.top_AP_idx > 0 & ...
                app.output.fiber_table.top_ML_idx > 0);
            if ismember('ID',fieldnames(app.output.fiber_table))
                [~,idx] = sort(app.output.fiber_table.ID);
                app.output.fiber_table = app.output.fiber_table(idx,:);
                app.FiberListBox.Items = cellstr(num2str(app.output.fiber_table.ID));
                app.output.tmp.ID_field = 'ID';
            else
                app.FiberListBox.Items = cellstr(num2str(app.output.fiber_table.top_ID));
                app.output.tmp.ID_field = 'top_ID';
            end
            
            % some relevant paths
            app.output.tmp.ct_path = fullfile(app.output.info.savepath,[app.output.info.ctname '.tif']);            
            app.output.tmp.ccf_path = fullfile(app.path_atlas.Value,'CCF','average_template_10_coronal.tif');
            app.output.tmp.labels_path = fullfile(app.path_atlas.Value,'CCF','annotation_10_coronal.tif');
            app.output.tmp.ccf_key = load(fullfile(app.path_atlas.Value,'CCF','ccf_key.mat'));
            app.output.tmp.chon_path = fullfile(app.path_atlas.Value,'Chon','Chon_labels_coronal.tif');
            app.output.tmp.chon_key = load(fullfile(app.path_atlas.Value,'Chon','chon_key.mat'));
            
            % load atlases: i thiiiiink this ends up being faster than
            % loading slice by slice when you need it            
            app.output.tmp.ccf = load_tiffs_fast(app.output.tmp.ccf_path);
            app.output.tmp.ct = load_tiffs_fast(app.output.tmp.ct_path);
            app.output.tmp.labels = load_tiffs_fast(app.output.tmp.labels_path);      
            app.output.tmp.chon = load_tiffs_fast(app.output.tmp.chon_path);
                                  
            % keep field
            if ~ismember('keep',fieldnames(app.output.fiber_table))
                disp('yes keep')
                app.output.fiber_table.keep = ones(size(app.output.fiber_table.top_ID));
            end
            app.count_keep.Value = sum(app.output.fiber_table.keep==1);
            app.count_reject.Value = sum(app.output.fiber_table.keep==0);
            app.count_unsure.Value = sum(app.output.fiber_table.keep==0.5);
            
            % preload the first ROI
            app.FiberListBox.Value = app.FiberListBox.Items{1};
            
            % update
            disp('loaded.')
            enable_components(app)                
            update_fiber(app)
        end
        
        function update_fiber(app)
            % get the row in the table of this fiber
            tbl_idx = find(app.output.fiber_table.(app.output.tmp.ID_field)==str2double(app.FiberListBox.Value));
            % fill in coordinates
            coord_fields = {'AP','DV','ML'};
            for c = 1:numel(coord_fields)
                app.([coord_fields{c} '_orig']).Value = app.output.fiber_table.(['bottom_' coord_fields{c} '_idx'])(tbl_idx);
                app.([coord_fields{c} '_new']).Value = app.output.fiber_table.(['bottom_' coord_fields{c} '_idx'])(tbl_idx);
                app.([coord_fields{c} '_slice']).Value = app.output.fiber_table.(['bottom_' coord_fields{c} '_idx'])(tbl_idx);
            end
            % fill in labels
            app.ccf_label_orig.Value = 'n/a';
            app.fp_label_orig.Value = 'n/a';
            app.ccf_label_new.Value = 'n/a';
            app.fp_label_new.Value = 'n/a';
            if ~isempty(app.output.fiber_table.ccf_label{tbl_idx})
                app.ccf_label_orig.Value = app.output.fiber_table.ccf_label{tbl_idx};
                app.ccf_label_new.Value = app.output.fiber_table.ccf_label{tbl_idx};
            end
            if ~isempty(app.output.fiber_table.chon_fp_label{tbl_idx})
                app.fp_label_orig.Value = app.output.fiber_table.chon_fp_label{tbl_idx};
                app.fp_label_new.Value = app.output.fiber_table.chon_fp_label{tbl_idx};
            end
            % keep/reject/unsure (1/0/.5)           
            if app.output.fiber_table.keep(tbl_idx) == 1
                app.keepButton.Value = 1;
            elseif app.output.fiber_table.keep(tbl_idx) == 0
                app.rejectButton.Value = 1;
            else
                app.unsureButton.Value = 1;
            end
            update_ct(app)
            update_atlas(app)        
        end
        
        function update_ct(app)
            view_fields = {'coronal','axial','sagittal'}; 
            xyz = [app.ML_orig.Value app.DV_orig.Value app.AP_orig.Value];
            xyz_plot = [1 2 3; 1 3 2; 3 2 1];
            tmp_slice.coronal = histeq(app.output.tmp.ct(:,:,app.AP_orig.Value));
            tmp_slice.axial = histeq(transpose(squeeze(app.output.tmp.ct(app.DV_orig.Value,:,:))));
            tmp_slice.sagittal = histeq(squeeze(app.output.tmp.ct(:,app.ML_orig.Value,:)));
            for v = 1:numel(view_fields)
                cla(app.(['axes_' view_fields{v} 'CT'])); % clear axis
                imagesc(app.(['axes_' view_fields{v} 'CT']),tmp_slice.(view_fields{v})); 
                colormap(app.(['axes_' view_fields{v} 'CT']),'gray')
                hold(app.(['axes_' view_fields{v} 'CT']),'on')
                plot(app.(['axes_' view_fields{v} 'CT']),xyz(xyz_plot(v,1)),xyz(xyz_plot(v,2)),'or','LineWidth',2)
                axis(app.(['axes_' view_fields{v} 'CT']),'equal')
            end                

        end
        
        function update_atlas(app)
            view_fields = {'coronal','axial','sagittal'};  
            coord_fields = {'AP','DV','ML'}; % the slices that go with the views
            xyz = [app.ML_new.Value app.DV_new.Value app.AP_new.Value];
            xyz_plot = [1 2 3; 1 3 2; 3 2 1];
            if app.view_labels.Value == 1
                tmp_slice.coronal = histeq(uint16(app.output.tmp.labels(:,:,app.AP_slice.Value)));
                tmp_slice.axial = histeq(uint16(transpose(squeeze(app.output.tmp.labels(app.DV_slice.Value,:,:)))));
                tmp_slice.sagittal = histeq(uint16(squeeze(app.output.tmp.labels(:,app.ML_slice.Value,:))));
            else
                tmp_slice.coronal = app.output.tmp.ccf(:,:,app.AP_slice.Value);
                tmp_slice.axial = transpose(squeeze(app.output.tmp.ccf(app.DV_slice.Value,:,:)));
                tmp_slice.sagittal = squeeze(app.output.tmp.ccf(:,app.ML_slice.Value,:));
            end
            for v = 1:numel(view_fields)
                cla(app.(['axes_' view_fields{v} 'Atlas']))
                imagesc(app.(['axes_' view_fields{v} 'Atlas']),tmp_slice.(view_fields{v}));  
                colormap(app.(['axes_' view_fields{v} 'Atlas']),'gray')
                if app.([coord_fields{v} '_slice']).Value == app.([coord_fields{v} '_new']).Value % if we're in the right slice for the fiber bottom
                    hold(app.(['axes_' view_fields{v} 'Atlas']),'on')
                    plot(app.(['axes_' view_fields{v} 'Atlas']),xyz(xyz_plot(v,1)),xyz(xyz_plot(v,2)),'or','LineWidth',2)
                end               
                axis(app.(['axes_' view_fields{v} 'Atlas']),'equal')                
            end
        end

        
        function new_label(app)

            % find ID
            app.output.tmp.ccf_ID = app.output.tmp.labels(app.DV_new.Value,app.ML_new.Value,app.AP_new.Value);
            [~,chon_nearest_ap_slice] = min(abs(app.output.tmp.chon_key.AP.AP-app.output.tmp.ccf_key.AP.AP(app.AP_new.Value)));
            app.output.tmp.chon_ID = app.output.tmp.chon(app.DV_new.Value,app.ML_new.Value,chon_nearest_ap_slice);            
            % get labels
            app.output.tmp.ccf_label = app.output.tmp.ccf_key.labels.name(app.output.tmp.ccf_key.labels.value==app.output.tmp.ccf_ID);
            app.output.tmp.chon_fp_label = app.output.tmp.chon_key.labels.Franklin_PaxinosFullName(app.output.tmp.chon_key.labels.StructuralID==app.output.tmp.chon_ID);
            app.output.tmp.chon_fp_label_abbrev = app.output.tmp.chon_key.labels.Franklin_PaxinosAbbreviation(app.output.tmp.chon_key.labels.StructuralID==app.output.tmp.chon_ID);
            app.output.tmp.chon_allen_label = app.output.tmp.chon_key.labels.AllenFullName(app.output.tmp.chon_key.labels.StructuralID==app.output.tmp.chon_ID);
            app.output.tmp.chon_allen_label_abbrev = app.output.tmp.chon_key.labels.AllenAbbreviation(app.output.tmp.chon_key.labels.StructuralID==app.output.tmp.chon_ID);            
            % update display
            app.ccf_label_new.Value = app.output.tmp.ccf_label;
            app.fp_label_new.Value = app.output.tmp.chon_fp_label;
            
        end
        
        function update_table(app)
                        
            % table index
            tbl_idx = find(app.output.fiber_table.(app.output.tmp.ID_field)==str2double(app.FiberListBox.Value));
            % update atlas ID value
            app.output.fiber_table.ccf_ID(tbl_idx) = app.output.tmp.ccf_ID;
            app.output.fiber_table.chon_ID(tbl_idx) = app.output.tmp.chon_ID;
            % update label text
            labels = {'ccf_label','chon_fp_label','chon_fp_label_abbrev','chon_allen_label','chon_allen_label_abbrev'};
            for la = 1:numel(labels)
                if ~isempty(app.output.tmp.(labels{la}))
                    app.output.fiber_table.(labels{la}){tbl_idx} = app.output.tmp.(labels{la});
                else
                    app.output.fiber_table.(labels{la}){tbl_idx} = '';
                end
            end
            % if opted in, save new indices too
            if app.save_idx_new.Value == 1
                app.output.fiber_table.bottom_AP_idx(tbl_idx) = app.AP_new.Value;
                app.output.fiber_table.bottom_ML_idx(tbl_idx) = app.ML_new.Value;
                app.output.fiber_table.bottom_DV_idx(tbl_idx) = app.DV_new.Value;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disable_components(app)
        end

        % Button pushed function: locate_atlas
        function locate_atlasButtonPushed(app, event)
            filepath = uigetdir;
            app.path_atlas.Value = filepath;            
        end

        % Button pushed function: load_fibers_button
        function load_fibers_buttonPushed(app, event)
            [filename,filepath] = uigetfile('*.mat');
            app.path_fiber.Value = fullfile([filepath,filename]);
            app.output = load(fullfile(filepath,filename));
            app.output.tmp.savepath = fullfile(filepath,filename);            
        end

        % Button pushed function: ok
        function okButtonPushed(app, event)
            if exist(app.path_fiber.Value,'file') && exist(app.path_atlas.Value,'dir')               
                app.load_fibers_button.Enable = 'off';
                app.path_fiber.Enable = 'off';
                app.locate_atlas.Enable = 'off';
                app.path_atlas.Enable = 'off';
                app.ok.Enable = 'off';
                pause(0.3) % just a little time to turn off some things
                load_things(app)                            
            end
        end

        % Value changed function: FiberListBox
        function FiberListBoxValueChanged(app, event)
            update_fiber(app)
        end

        % Selection changed function: atlas_View
        function atlas_ViewSelectionChanged(app, event)
            %selectedButton = app.atlas_View.SelectedObject;
            update_atlas(app)
        end

        % Value changed function: AP_slice
        function AP_sliceValueChanged(app, event)
            %value = app.AP_slice.Value;
            update_atlas(app)
        end

        % Value changed function: DV_slice
        function DV_sliceValueChanged(app, event)
            %value = app.DV_slice.Value;
            update_atlas(app)
        end

        % Value changed function: ML_slice
        function ML_sliceValueChanged(app, event)
            %value = app.ML_slice.Value;
            update_atlas(app)
        end

        % Selection changed function: keep_reject_buttons
        function keep_reject_buttonsSelectionChanged(app, event)
            tbl_idx = find(app.output.fiber_table.(app.output.tmp.ID_field)==str2double(app.FiberListBox.Value));
            if app.keepButton.Value == 1
                app.output.fiber_table.keep(tbl_idx) = 1;
            elseif app.rejectButton.Value == 1
                app.output.fiber_table.keep(tbl_idx) = 0;
            else
                app.output.fiber_table.keep(tbl_idx) = 0.5;
            end
            % update counts
            app.count_keep.Value = sum(app.output.fiber_table.keep==1);
            app.count_reject.Value = sum(app.output.fiber_table.keep==0);
            app.count_unsure.Value = sum(app.output.fiber_table.keep==0.5);
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            % get the row in the table of this fiber
            tbl_idx1 = (app.output.fiber_table.(app.output.tmp.ID_field)==str2double(app.FiberListBox.Value));
            tbl_idx2 = (app.output.fiber_table_orig.(app.output.tmp.ID_field)==str2double(app.FiberListBox.Value));
            % fill in coordinates
            coord_fields = {'AP','DV','ML'};
            for c = 1:numel(coord_fields)
                app.output.fiber_table.(['bottom_' coord_fields{c} '_idx'])(tbl_idx1) = app.output.fiber_table_orig.(['bottom_' coord_fields{c} '_idx'])(tbl_idx2);               
            end
            % fill in labels
            app.output.fiber_table_orig.ccf_label{tbl_idx2} = app.output.fiber_table.ccf_label{tbl_idx1};
            app.output.fiber_table_orig.fp_label{tbl_idx2} = app.output.fiber_table.fp_label{tbl_idx1};
            app.output.fiber_table_orig.ccf_label_abbrev{tbl_idx2} = app.output.fiber_table.ccf_label_abbrev{tbl_idx1};
            app.output.fiber_table_orig.fp_label_abbrev{tbl_idx2} = app.output.fiber_table.fp_label_abbrev{tbl_idx1};
            
            if ismember('keep',fieldnames(app.output.fiber_table_orig))
                app.output.fiber_table.keep(tbl_idx1) = app.output.fiber_table_orig.keep(tbl_idx2);
            else
                app.output.fiber_table.keep(tbl_idx1) = 1;
            end
            update_fiber(app)
        end

        % Value changed function: AP_new
        function AP_newValueChanged(app, event)
            new_label(app)            
        end

        % Value changed function: ML_new
        function ML_newValueChanged(app, event)
            new_label(app)            
        end

        % Value changed function: DV_new
        function DV_newValueChanged(app, event)
            new_label(app)            
        end

        % Button pushed function: save_new_label
        function save_new_labelPushed(app, event)
            update_table(app)
        end

        % Button pushed function: click_sagittal
        function click_sagittalButtonPushed(app, event)
            if isfield(app.output.tmp,'sp')
                delete(app.output.tmp.sp)
            end
            app.output.tmp.sp = drawpoint('Parent',app.axes_sagittalAtlas);
            x = app.output.tmp.sp.Position(1);
            y = app.output.tmp.sp.Position(2);
            app.AP_new.Value = round(x);
            app.ML_new.Value = app.ML_slice.Value;                
            app.DV_new.Value = round(y);
            new_label(app)
        end

        % Button pushed function: click_axial
        function click_axialButtonPushed(app, event)
            if isfield(app.output.tmp,'ap')
                delete(app.output.tmp.ap)
            end
            app.output.tmp.ap = drawpoint('Parent',app.axes_axialAtlas);
            x = app.output.tmp.ap.Position(1);
            y = app.output.tmp.ap.Position(2);
            app.AP_new.Value = round(y);
            app.ML_new.Value = round(x);
            app.DV_new.Value = app.DV_slice.Value;
            new_label(app)
        end

        % Button pushed function: click_coronal
        function click_coronalButtonPushed(app, event)
            if isfield(app.output.tmp,'cp')
                delete(app.output.tmp.cp)
            end
            app.output.tmp.cp = drawpoint('Parent',app.axes_coronalAtlas);
            x = app.output.tmp.cp.Position(1);
            y = app.output.tmp.cp.Position(2);
            app.AP_new.Value = app.AP_slice.Value;
            app.ML_new.Value= round(x);
            app.DV_new.Value = round(y);
            new_label(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            disp(['saving ' datestr(now)])
            this_output = app.output;            
            if isfield(this_output,'tmp')
                this_output = rmfield(this_output,'tmp');
            end
            save(app.output.tmp.savepath,'-struct','this_output')
            disp('saved.')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FiberListBoxLabel = uilabel(app.UIFigure);
            app.FiberListBoxLabel.Text = 'Fiber #';
            app.FiberListBox = uilistbox(app.UIFigure);
            app.FiberListBox.Items = {'1', '2', '3', '4', '5', ''};
            app.FiberListBox.ValueChangedFcn = createCallbackFcn(app, @FiberListBoxValueChanged, true);
            app.FiberListBox.Value = '1';
            app.load_fibers_button = uibutton(app.UIFigure, 'push');
            app.load_fibers_button.ButtonPushedFcn = createCallbackFcn(app, @load_fibers_buttonPushed, true);
            app.load_fibers_button.Text = 'Locate fiber file';
            app.fiber_counts_panel = uipanel(app.UIFigure);
            app.fiber_counts_panel.Title = 'Fiber counts';
            app.rejectLabel = uilabel(app.fiber_counts_panel);
            app.rejectLabel.Text = 'reject';
            app.count_reject = uieditfield(app.fiber_counts_panel, 'numeric');
            app.count_reject.Editable = 'off';
            app.unsureLabel = uilabel(app.fiber_counts_panel);
            app.unsureLabel.Text = 'unsure';
            app.count_unsure = uieditfield(app.fiber_counts_panel, 'numeric');
            app.count_unsure.Editable = 'off';
            app.keepLabel = uilabel(app.fiber_counts_panel);
            app.keepLabel.Text = 'keep';
            app.count_keep = uieditfield(app.fiber_counts_panel, 'numeric');
            app.count_keep.Editable = 'off';
            app.current_location_panel = uipanel(app.UIFigure);
            app.current_location_panel.Title = 'Currently Assigned Location';
            app.idx_orig = uipanel(app.current_location_panel);
            app.APindexLabel_3 = uilabel(app.idx_orig);
            app.APindexLabel_3.Text = 'AP index';
            app.AP_orig = uieditfield(app.idx_orig, 'numeric');
            app.AP_orig.Editable = 'off';
            app.MLindexLabel_3 = uilabel(app.idx_orig);
            app.MLindexLabel_3.Text = 'ML index';
            app.ML_orig = uieditfield(app.idx_orig, 'numeric');
            app.ML_orig.Editable = 'off';
            app.DVindexLabel_3 = uilabel(app.idx_orig);
            app.DVindexLabel_3.Text = 'DV index';
            app.DV_orig = uieditfield(app.idx_orig, 'numeric');
            app.DV_orig.Editable = 'off';
            app.keep_reject_buttons = uibuttongroup(app.current_location_panel);
            app.keep_reject_buttons.SelectionChangedFcn = createCallbackFcn(app, @keep_reject_buttonsSelectionChanged, true);
            app.keepButton = uiradiobutton(app.keep_reject_buttons);
            app.keepButton.Text = 'keep';
            app.keepButton.Value = true;
            app.rejectButton = uiradiobutton(app.keep_reject_buttons);
            app.rejectButton.Text = 'reject';
            app.unsureButton = uiradiobutton(app.keep_reject_buttons);
            app.unsureButton.Text = 'unsure';
            app.Reset = uibutton(app.current_location_panel, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = {'Reset to original'; ''};
            app.fp_label_orig = uitextarea(app.current_location_panel);
            app.fp_label_orig.Editable = 'off';
            app.ccf_label_orig = uitextarea(app.current_location_panel);
            app.ccf_label_orig.Editable = 'off';
            app.new_location_panel = uipanel(app.UIFigure);
            app.new_location_panel.Title = 'Candidate Alternate Location';
            app.fp_label_new = uitextarea(app.new_location_panel);
            app.fp_label_new.Editable = 'off';
            app.save_new_label = uibutton(app.new_location_panel, 'push');
            app.save_new_label.ButtonPushedFcn = createCallbackFcn(app, @save_new_labelPushed, true);
            app.save_new_label.Text = 'Save new label';
            app.idx_new = uipanel(app.new_location_panel);
            app.APindexLabel_4 = uilabel(app.idx_new);
            app.APindexLabel_4.Text = 'AP index';
            app.AP_new = uieditfield(app.idx_new, 'numeric');
            app.AP_new.ValueChangedFcn = createCallbackFcn(app, @AP_newValueChanged, true);
            app.MLindexLabel_4 = uilabel(app.idx_new);
            app.MLindexLabel_4.Text = 'ML index';
            app.ML_new = uieditfield(app.idx_new, 'numeric');
            app.ML_new.ValueChangedFcn = createCallbackFcn(app, @ML_newValueChanged, true);
            app.DVindexLabel_4 = uilabel(app.idx_new);
            app.DVindexLabel_4.Text = 'DV index';
            app.DV_new = uieditfield(app.idx_new, 'numeric');
            app.DV_new.ValueChangedFcn = createCallbackFcn(app, @DV_newValueChanged, true);
            app.ccf_label_new = uitextarea(app.new_location_panel);
            app.ccf_label_new.Editable = 'off';
            app.save_idx_new = uicheckbox(app.new_location_panel);
            app.save_idx_new.Text = 'save with new indices';
            app.atlas_View = uibuttongroup(app.UIFigure);
            app.atlas_View.SelectionChangedFcn = createCallbackFcn(app, @atlas_ViewSelectionChanged, true);
            app.atlas_View.Title = 'Atlas view';
            app.view_structural = uiradiobutton(app.atlas_View);
            app.view_structural.Text = 'structural';
            app.view_structural.Value = true;
            app.view_labels = uiradiobutton(app.atlas_View);
            app.view_labels.Text = 'labels';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.locate_atlas = uibutton(app.UIFigure, 'push');
            app.locate_atlas.ButtonPushedFcn = createCallbackFcn(app, @locate_atlasButtonPushed, true);
            app.locate_atlas.Text = 'Locate atlas folder';
            app.path_atlas = uieditfield(app.UIFigure, 'text');
            app.path_atlas.Value = '/path/to/MRIAtlas';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'The CT registration won''t be perfect, so in some cases (when your recording location is near an anatomical boundary, for example), you may disagree with the assigned atlas label. Click through the fibers from the listbox to manually verify these labels. If you disagree with a label, click "click" in one of the atlas views, and then click a point in the atlas that you believe is more suitable for your recording location. The new alternative label (and location) will show up in the box at the bottom right. You can then opt to save the new label. You can also opt to save the new location itself. You can always reset the fiber back to its original location and label. You can view the structural atlas, or the segmented atlas using the Atlas view panel, and you can move through the slices using the Slice panel. The keep/reject/unsure categorization at the bottom is an optional feature that may be useful. You can click Save as often as you wish.'};
            app.coronalPanel = uipanel(app.UIFigure);
            app.click_coronal = uibutton(app.coronalPanel, 'push');
            app.click_coronal.ButtonPushedFcn = createCallbackFcn(app, @click_coronalButtonPushed, true);
            app.click_coronal.Text = 'Click';
            app.APLabel = uilabel(app.coronalPanel);
            app.APLabel.Text = 'AP';
            app.AP_slice = uieditfield(app.coronalPanel, 'numeric');
            app.AP_slice.ValueChangedFcn = createCallbackFcn(app, @AP_sliceValueChanged, true);
            app.axes_coronalCT = uiaxes(app.coronalPanel);
            title(app.axes_coronalCT, 'Coronal')
            xlabel(app.axes_coronalCT, 'ML')
            ylabel(app.axes_coronalCT, 'DV')
            zlabel(app.axes_coronalCT, 'Z')
            app.axes_coronalCT.XTick = [];
            app.axes_coronalCT.YTick = [];
            app.axes_coronalAtlas = uiaxes(app.coronalPanel);
            xlabel(app.axes_coronalAtlas, 'ML')
            ylabel(app.axes_coronalAtlas, 'DV')
            zlabel(app.axes_coronalAtlas, 'Z')
            app.axes_coronalAtlas.XTick = [];
            app.axes_coronalAtlas.YTick = [];
            app.axialPanel = uipanel(app.UIFigure);
            app.click_axial = uibutton(app.axialPanel, 'push');
            app.click_axial.ButtonPushedFcn = createCallbackFcn(app, @click_axialButtonPushed, true);
            app.click_axial.Text = 'Click';
            app.DVLabel = uilabel(app.axialPanel);
            app.DVLabel.Text = 'DV';
            app.DV_slice = uieditfield(app.axialPanel, 'numeric');
            app.DV_slice.ValueChangedFcn = createCallbackFcn(app, @DV_sliceValueChanged, true);
            app.axes_axialAtlas = uiaxes(app.axialPanel);
            xlabel(app.axes_axialAtlas, 'ML')
            ylabel(app.axes_axialAtlas, 'AP')
            zlabel(app.axes_axialAtlas, 'Z')
            app.axes_axialAtlas.XTick = [];
            app.axes_axialAtlas.YTick = [];
            app.axes_axialCT = uiaxes(app.axialPanel);
            title(app.axes_axialCT, 'Axial')
            xlabel(app.axes_axialCT, 'ML')
            ylabel(app.axes_axialCT, 'AP')
            zlabel(app.axes_axialCT, 'Z')
            app.axes_axialCT.XTick = [];
            app.axes_axialCT.YTick = [];
            app.sagittalPanel = uipanel(app.UIFigure);
            app.click_sagittal = uibutton(app.sagittalPanel, 'push');
            app.click_sagittal.ButtonPushedFcn = createCallbackFcn(app, @click_sagittalButtonPushed, true);
            app.click_sagittal.Text = 'Click';
            app.MLLabel = uilabel(app.sagittalPanel);
            app.MLLabel.Text = 'ML';
            app.ML_slice = uieditfield(app.sagittalPanel, 'numeric');
            app.ML_slice.ValueChangedFcn = createCallbackFcn(app, @ML_sliceValueChanged, true);
            app.axes_sagittalCT = uiaxes(app.sagittalPanel);
            title(app.axes_sagittalCT, 'Sagittal')
            xlabel(app.axes_sagittalCT, 'AP')
            ylabel(app.axes_sagittalCT, 'DV')
            zlabel(app.axes_sagittalCT, 'Z')
            app.axes_sagittalCT.XTick = [];
            app.axes_sagittalCT.YTick = [];
            app.axes_sagittalAtlas = uiaxes(app.sagittalPanel);
            xlabel(app.axes_sagittalAtlas, 'AP')
            ylabel(app.axes_sagittalAtlas, 'DV')
            zlabel(app.axes_sagittalAtlas, 'Z')
            app.axes_sagittalAtlas.XTick = [];
            app.axes_sagittalAtlas.YTick = [];
            app.path_fiber = uieditfield(app.UIFigure, 'text');
            app.path_fiber.Value = '/path/to/fibers.mat';
            app.ok = uibutton(app.UIFigure, 'push');
            app.ok.ButtonPushedFcn = createCallbackFcn(app, @okButtonPushed, true);
            app.ok.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = verify_atlas_labels

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end